classdef Transformadores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProgramacinAvanzadaDiegoVillegasGoveaUIFigure  matlab.ui.Figure
        InicioMenu                      matlab.ui.container.Menu
        ResumenMenu                     matlab.ui.container.Menu
        AcercadeMenu                    matlab.ui.container.Menu
        DedicatoriaMenu                 matlab.ui.container.Menu
        ClculodeEficienciayRegulacindeunTransformadorReductorLabel  matlab.ui.control.Label
        PUBasePanel                     matlab.ui.container.Panel
        SbEditFieldLabel                matlab.ui.control.Label
        SbEditField                     matlab.ui.control.NumericEditField
        Vb1EditFieldLabel               matlab.ui.control.Label
        Vb1EditField                    matlab.ui.control.NumericEditField
        Vb2EditFieldLabel               matlab.ui.control.Label
        Vb2EditField                    matlab.ui.control.NumericEditField
        Ib1EditFieldLabel               matlab.ui.control.Label
        Ib1EditField                    matlab.ui.control.NumericEditField
        Ib2EditFieldLabel               matlab.ui.control.Label
        Ib2EditField                    matlab.ui.control.NumericEditField
        KVALabel_3                      matlab.ui.control.Label
        ALabel_8                        matlab.ui.control.Label
        VLabel_13                       matlab.ui.control.Label
        VLabel_12                       matlab.ui.control.Label
        ALabel_9                        matlab.ui.control.Label
        DATOSDEPLACAPanel               matlab.ui.container.Panel
        RelacindetransformacinLabel     matlab.ui.control.Label
        EditFieldLabel                  matlab.ui.control.Label
        LadoDeBajaEditField             matlab.ui.control.NumericEditField
        PotenciaaparenteEditField_2Label  matlab.ui.control.Label
        PotenciaAparenteEditField       matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        LadoDeAltaEditField             matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel        matlab.ui.control.Label
        FrecuenciaEditField             matlab.ui.control.NumericEditField
        KVALabel                        matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        VLabel_14                       matlab.ui.control.Label
        DATOSDEPRUEBASPanel             matlab.ui.container.Panel
        LadodebajaCALabel               matlab.ui.control.Label
        LadodealtaCSLabel               matlab.ui.control.Label
        VoltajeEditFieldLabel           matlab.ui.control.Label
        VoltajeEditField                matlab.ui.control.NumericEditField
        CorrienteEditFieldLabel         matlab.ui.control.Label
        CorrienteEditField              matlab.ui.control.NumericEditField
        PotenciaEditFieldLabel          matlab.ui.control.Label
        PotenciaEditField               matlab.ui.control.NumericEditField
        CorrienteEditField_2Label       matlab.ui.control.Label
        CorrienteEditField_2            matlab.ui.control.NumericEditField
        VoltajeEditField_2Label         matlab.ui.control.Label
        VoltajeEditField_2              matlab.ui.control.NumericEditField
        PotenciaEditField_2Label        matlab.ui.control.Label
        PotenciaEditField_2             matlab.ui.control.NumericEditField
        VLabel_5                        matlab.ui.control.Label
        ALabel_5                        matlab.ui.control.Label
        WLabel_5                        matlab.ui.control.Label
        VLabel_6                        matlab.ui.control.Label
        ALabel_6                        matlab.ui.control.Label
        WLabel_6                        matlab.ui.control.Label
        CALCULOSPanel                   matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        VocTab                          matlab.ui.container.Tab
        VocLabel                        matlab.ui.control.Label
        Vb2Label                        matlab.ui.control.Label
        VcaLabel_2                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        EditField_Vca                   matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        VocLabel_3                      matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        puLabel                         matlab.ui.control.Label
        IocTab                          matlab.ui.container.Tab
        IocLabel                        matlab.ui.control.Label
        Ib2Label                        matlab.ui.control.Label
        IcaLabel                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        IocLabel_3                      matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        puLabel_2                       matlab.ui.control.Label
        PocTab                          matlab.ui.container.Tab
        PocLabel                        matlab.ui.control.Label
        SbLabel                         matlab.ui.control.Label
        PcaLabel                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        PocLabel_3                      matlab.ui.control.Label
        EditField_12                    matlab.ui.control.NumericEditField
        puLabel_3                       matlab.ui.control.Label
        PerdidasenelncleoLabel          matlab.ui.control.Label
        VscTab                          matlab.ui.container.Tab
        VscLabel                        matlab.ui.control.Label
        Vb1Label                        matlab.ui.control.Label
        VcsLabel                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        VscLabel_3                      matlab.ui.control.Label
        EditField_15                    matlab.ui.control.NumericEditField
        puLabel_4                       matlab.ui.control.Label
        IscTab                          matlab.ui.container.Tab
        IscLabel                        matlab.ui.control.Label
        Ib1Label                        matlab.ui.control.Label
        IcsLabel                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        ALabel_3                        matlab.ui.control.Label
        ALabel_4                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        IscLabel_3                      matlab.ui.control.Label
        EditField_18                    matlab.ui.control.NumericEditField
        puLabel_5                       matlab.ui.control.Label
        PscTab                          matlab.ui.container.Tab
        PscLabel                        matlab.ui.control.Label
        SbLabel_2                       matlab.ui.control.Label
        PcsLabel                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        WLabel_3                        matlab.ui.control.Label
        WLabel_4                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        PscLabel_2                      matlab.ui.control.Label
        EditField_21                    matlab.ui.control.NumericEditField
        puLabel_6                       matlab.ui.control.Label
        PerdidasdelconductorLabel       matlab.ui.control.Label
        YTab                            matlab.ui.container.Tab
        YLabel                          matlab.ui.control.Label
        VocLabel_2                      matlab.ui.control.Label
        IocLabel_2                      matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        EditField_22                    matlab.ui.control.NumericEditField
        EditField_23                    matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        EditField_24                    matlab.ui.control.NumericEditField
        puLabel_7                       matlab.ui.control.Label
        PocTab_2                        matlab.ui.container.Tab
        PocVocIoccosLabel               matlab.ui.control.Label
        PocLabel_2                      matlab.ui.control.Label
        VocIocLabel                     matlab.ui.control.Label
        cos1Label                       matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        EditField_27                    matlab.ui.control.NumericEditField
        Label_30                        matlab.ui.control.Label
        cos1Label_2                     matlab.ui.control.Label
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        Label_29                        matlab.ui.control.Label
        EditField_30                    matlab.ui.control.NumericEditField
        YTab_2                          matlab.ui.container.Tab
        YLabel_3                        matlab.ui.control.Label
        EditField_38                    matlab.ui.control.NumericEditField
        LLabel                          matlab.ui.control.Label
        EditField_39                    matlab.ui.control.NumericEditField
        Label_38                        matlab.ui.control.Label
        ZeTab                           matlab.ui.container.Tab
        ZeLabel                         matlab.ui.control.Label
        IscLabel_2                      matlab.ui.control.Label
        VscLabel_2                      matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        EditField_31                    matlab.ui.control.NumericEditField
        EditField_32                    matlab.ui.control.NumericEditField
        ZeLabel_2                       matlab.ui.control.Label
        EditField_33                    matlab.ui.control.NumericEditField
        puLabel_8                       matlab.ui.control.Label
        ZeTab_2                         matlab.ui.container.Tab
        PscVscIsccosLabel               matlab.ui.control.Label
        PscLabel_3                      matlab.ui.control.Label
        VscIscLabel                     matlab.ui.control.Label
        cos1Label_3                     matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        EditField_34                    matlab.ui.control.NumericEditField
        Label_34                        matlab.ui.control.Label
        cos1Label_4                     matlab.ui.control.Label
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        Label_35                        matlab.ui.control.Label
        EditField_37                    matlab.ui.control.NumericEditField
        ZTab                            matlab.ui.container.Tab
        ZLabel                          matlab.ui.control.Label
        EditField_40                    matlab.ui.control.NumericEditField
        LLabel_2                        matlab.ui.control.Label
        EditField_41                    matlab.ui.control.NumericEditField
        Label_39                        matlab.ui.control.Label
        PLENACARGAPanel                 matlab.ui.container.Panel
        PotenciaaparenteEditFieldLabel  matlab.ui.control.Label
        PotenciaaparenteEditField       matlab.ui.control.NumericEditField
        FactordepotenciaEditFieldLabel  matlab.ui.control.Label
        FactordepotenciaEditField       matlab.ui.control.NumericEditField
        VoltajeEditField_3Label         matlab.ui.control.Label
        VoltajeEditField_3              matlab.ui.control.NumericEditField
        CorrienteEditField_3Label       matlab.ui.control.Label
        CorrienteEditField_3            matlab.ui.control.NumericEditField
        KVALabel_2                      matlab.ui.control.Label
        Label_40                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        ALabel_7                        matlab.ui.control.Label
        Label_51                        matlab.ui.control.Label
        CONTROLESButtonGroup            matlab.ui.container.ButtonGroup
        LimpiarCamposSwitchLabel        matlab.ui.control.Label
        LimpiarCamposSwitch             matlab.ui.control.RockerSwitch
        CalcularButton                  matlab.ui.control.Button
        EFICIENCIAPanel                 matlab.ui.container.Panel
        Label_41                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        EditField_44Label               matlab.ui.control.Label
        EditField_44                    matlab.ui.control.NumericEditField
        SFPLabel                        matlab.ui.control.Label
        SFPPcobrePncleoLabel            matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        REGULACINDEVOLTAJEPanel         matlab.ui.container.Panel
        V1I2ZV2Label                    matlab.ui.control.Label
        I2Label                         matlab.ui.control.Label
        EditField_42                    matlab.ui.control.NumericEditField
        LLabel_3                        matlab.ui.control.Label
        EditField_43                    matlab.ui.control.NumericEditField
        TomandoV2Label                  matlab.ui.control.Label
        V1EditFieldLabel                matlab.ui.control.Label
        V1EditField                     matlab.ui.control.NumericEditField
        RVEditFieldLabel                matlab.ui.control.Label
        RVEditField                     matlab.ui.control.NumericEditField
        Label_43                        matlab.ui.control.Label
        VLabel_11                       matlab.ui.control.Label
        LLabel_4                        matlab.ui.control.Label
        EditField_45                    matlab.ui.control.NumericEditField
        ALabel_10                       matlab.ui.control.Label
        RegulacindeVoltajeLabel         matlab.ui.control.Label
        RegulacindeVoltajeGauge         matlab.ui.control.SemicircularGauge
        EficienciaLabel                 matlab.ui.control.Label
        EficienciaGauge                 matlab.ui.control.SemicircularGauge
        Label_48                        matlab.ui.control.Label
        Label_49                        matlab.ui.control.Label
        Label_50                        matlab.ui.control.Label
    end


    methods (Access = public)
    
        function myprogress1(app)
                        f = uifigure;
                        d = uiprogressdlg(f,'Title','Please Wait',...
                            'Message','Opening the application');
                        pause(.5)
                        %Datos de placa
                Q=app.PotenciaAparenteEditField.Value;
                RTa=app.LadoDeAltaEditField.Value;
                RTb=app.LadoDeBajaEditField.Value;
                F=app.FrecuenciaEditField.Value;
                %Datos de pruebas
                Vca=app.VoltajeEditField.Value;
                Ica=app.CorrienteEditField.Value;
                Pca=app.PotenciaEditField.Value;
                Vcs=app.VoltajeEditField_2.Value;
                Ics=app.CorrienteEditField_2.Value;
                Pcs=app.PotenciaEditField_2.Value;
                %Plena carga
                PQ=app.PotenciaaparenteEditField.Value;
                FP=app.FactordepotenciaEditField.Value;
                PV=app.VoltajeEditField_3.Value;
                PI=app.CorrienteEditField_3.Value;
                        %PU Base
                        Sb=1000*app.SbEditField.Value;
                        Vb1=app.Vb1EditField.Value;
                        Vb2=app.Vb2EditField.Value;
                        Ib1=app.Ib1EditField.Value;
                        Ib2=app.Ib2EditField.Value;
                        %Voc
                        app.EditField_Vca.Value=Vca;
                        app.EditField_5.Value=Vb2; 
                        Voc=Vca/Vb2;
                        app.EditField_6.Value=Voc;
                        %Ioc
                        app.EditField_7.Value=Ica;
                        app.EditField_8.Value=Ib2;
                        Ioc=Ica/Ib2;
                        app.EditField_9.Value=Ioc;
                        %Poc
                        app.EditField_10.Value=Pca;
                        app.EditField_11.Value=Sb;
                        Poc=Pca/Sb;
                        app.EditField_12.Value=Poc;
                        %Vsc
                        app.EditField_13.Value=Vcs;
                        app.EditField_14.Value=Vb1;
                        Vsc=Vcs/Vb1;
                        app.EditField_15.Value=Vsc;
                        %Isc
                        app.EditField_16.Value=Ics;
                        app.EditField_17.Value=Ib1;
                        Isc=Ics/Ib1;
                        app.EditField_18.Value=Isc;
                        %Psc
                        app.EditField_19.Value=Pcs;
                        app.EditField_20.Value=Sb;
                        Psc=Pcs/Sb;
                        app.EditField_21.Value=Psc;
                        
                        %Y
                        app.EditField_22.Value=Ioc;
                        app.EditField_23.Value=Voc;
                        Ymag=Ioc/Voc;
                        app.EditField_24.Value=Ymag;
                        %Poc
                        app.EditField_28.Value=Poc;
                        app.EditField_29.Value=Voc;
                        app.EditField_30.Value=Ioc;
                        tetha=-acosd(Poc/(Voc*Ioc));
                        app.EditField_27.Value=tetha;
                        %Y
                        app.EditField_38.Value=Ymag;
                        app.EditField_39.Value=tetha;
                        %Ze
                        app.EditField_31.Value=Vsc;
                        app.EditField_32.Value=Isc;
                        Ze=Vsc/Isc;
                        app.EditField_33.Value=Ze;
                        
                        app.EditField_37.Value=Isc;
                        app.EditField_35.Value=Psc;
                        app.EditField_36.Value=Vsc;
                        ZeTetha=acosd(Psc/(Vsc*Isc));
                        app.EditField_34.Value=ZeTetha;
                        %Z
                        app.EditField_40.Value=Ze;
                        app.EditField_41.Value=ZeTetha;


                        PU=PQ*1/app.SbEditField.Value;
                        PUV=PV*1/Vca;
                        PUIb2=PI*1/Ib2;
                        
                        Iang=acosd(FP);
                        [Ireal,Iimag]=pol2cart(-Iang,PUIb2);
                        I2=complex(Ireal,Iimag);
                        
                        app.EditField_42.Value=PUIb2;
                        app.EditField_43.Value=-Iang;
                        
                        [Zreal,Zimag]=pol2cart(ZeTetha,Ze);
                        ZE=complex(Zreal,Zimag);
                        
                        [PUVreal,PUVimag]=pol2cart(0,PUV);
                        puv=complex(PUVreal,PUVimag);
                        
                        V1=(I2*ZE)+puv;
                        
                        [V1ang,V1mag] = cart2pol(real(V1),imag(V1));
                        app.V1EditField.Value=V1mag;
                        app.EditField_45.Value=V1ang*180/pi;
                        
                        R=((V1mag-PUV)/PUV)*100;
                        app.RVEditField.Value=R;
                        
                        n=((FP*PU)/((FP*PU)+(Psc+Poc)))*100;
                        
                        app.EditField_44.Value=n;
                        
                        app.RegulacindeVoltajeGauge.Value=R;
                        app.EficienciaGauge.Value=n;
        end
        
    end


    methods (Access = private)

        % Menu selected function: AcercadeMenu
        function AcercadeMenuSelected(app, event)
            message = sprintf('Desarrollado por Diego Villegas Govea el 24 de noviembre de 2018, para la materia de Programación Avanzada. \n\nEstudiante del Tecnológico Nacional de México campus Morelia, de la carrera de  Ingeniería Eléctrica con especialidad en Sistemas Eléctricos de Potencia.');
            uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,message,'Acerca de','Icon','success');
        end

        % Menu selected function: ResumenMenu
        function ResumenMenuSelected(app, event)
            message = sprintf('Cálculo de la Eficiencia y Regulación de Voltaje de un transformador reductor. \n\n La impedancia es la misma en ambos devanados del transformador, dado que no importa si es en el devanado primario o secundario.');
            uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,message,'Resumen','Icon','info');
       
        end

        % Value changed function: LimpiarCamposSwitch
        function LimpiarCamposSwitchValueChanged(app, event)
            value = app.LimpiarCamposSwitch.Value;
            %uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,value,'Value Switch','Icon','info');
            switch value
                case 'On'
               
                    f = waitbar(0,'Espere porfavor...');
                    pause(.5)
                    

                    %Datos de placa
                    app.PotenciaAparenteEditField.Value = 0;
                    app.LadoDeAltaEditField.Value = 0;
                    app.LadoDeBajaEditField.Value = 0;
                    app.FrecuenciaEditField.Value = 60;
                    %Datos de pruebas
                    app.VoltajeEditField.Value = 0;
                    app.CorrienteEditField.Value =0;
                    app.PotenciaEditField.Value =0;
                    app.VoltajeEditField_2.Value = 0;
                    app.CorrienteEditField_2.Value =0;
                    app.PotenciaEditField_2.Value =0;
                    
                    waitbar(.33,f,'Limpiando campos');
                    pause(0.5)
                    
                    %PU Base
                    app.SbEditField.Value=0;
                    app.Vb1EditField.Value=0;
                    app.Vb2EditField.Value=0;
                    app.Ib1EditField.Value=0;
                    app.Ib2EditField.Value=0;
                    %Eficiencia
                    app.EditField_44.Value=0;
                    %Regulación
                    app.EditField_42.Value=0;
                    app.EditField_43.Value=0;
                    app.EditField_45.Value=0;
                    app.V1EditField.Value=0;
                    app.RVEditField.Value=0;
                    %Plena carga
                    app.PotenciaaparenteEditField.Value=0;
                    app.FactordepotenciaEditField.Value=0;
                    app.VoltajeEditField_3.Value=0;
                    app.CorrienteEditField_3.Value=0;
                    %Voc
                    app.EditField_Vca.Value=0;
                    app.EditField_5.Value=0; 
                    app.EditField_6.Value=0;
                    %Ioc
                    app.EditField_7.Value=0;
                    app.EditField_8.Value=0;
                    app.EditField_9.Value=0;
                    %Poc
                    app.EditField_10.Value=0;
                    app.EditField_11.Value=0;
                    app.EditField_12.Value=0;
                    %Vsc
                    app.EditField_13.Value=0;
                    app.EditField_14.Value=0;
                    app.EditField_15.Value=0;
                    %Isc
                    app.EditField_16.Value=0;
                    app.EditField_17.Value=0;
                    app.EditField_18.Value=0;
                    %Psc
                    app.EditField_19.Value=0;
                    app.EditField_20.Value=0;
                    app.EditField_21.Value=0;
                    
                    waitbar(.67,f,'Seguimos limpiando los campos');
                    pause(0.5)
                    
                    %Y
                    app.EditField_22.Value=0;
                    app.EditField_23.Value=0;
                    app.EditField_24.Value=0;
                    %Poc
                    app.EditField_27.Value=0;
                    app.EditField_28.Value=0;
                    app.EditField_29.Value=0;
                    app.EditField_30.Value=0;
                    %Y
                    app.EditField_38.Value=0;
                    app.EditField_39.Value=0;
                    %Ze
                    app.EditField_31.Value=0;
                    app.EditField_32.Value=0;
                    app.EditField_33.Value=0;
                    app.EditField_34.Value=0;
                    app.EditField_35.Value=0;
                    app.EditField_36.Value=0;
                    app.EditField_37.Value=0;
                    %Z
                    app.EditField_40.Value=0;
                    app.EditField_41.Value=0;
                    
                    app.RegulacindeVoltajeGauge.Value=0;
                    app.EficienciaGauge.Value=0;
                    
                    waitbar(1,f,'Finalizando');
                    pause(.5)
                    close(f)   
                    
                    app.LimpiarCamposSwitch.Value = 'Off';
                case 'Off'
            end
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            %Datos de placa
                Q=app.PotenciaAparenteEditField.Value;
                RTa=app.LadoDeAltaEditField.Value;
                RTb=app.LadoDeBajaEditField.Value;
                F=app.FrecuenciaEditField.Value;
                %Datos de pruebas
                Vca=app.VoltajeEditField.Value;
                Ica=app.CorrienteEditField.Value;
                Pca=app.PotenciaEditField.Value;
                Vcs=app.VoltajeEditField_2.Value;
                Ics=app.CorrienteEditField_2.Value;
                Pcs=app.PotenciaEditField_2.Value;
                %Plena carga
                PQ=app.PotenciaaparenteEditField.Value;
                FP=app.FactordepotenciaEditField.Value;
                PV=app.VoltajeEditField_3.Value;
                PI=app.CorrienteEditField_3.Value;
                if (Q==0||RTa==0||RTb==0||Vca==0||Ica==0||Pca==0||Vcs==0||Ics==0||Pcs==0||PQ==0||FP==0||PV==0||PI==0)
                    
                    message = sprintf('Datos ingresados incorrectamente, revise que no existan campos vacíos.');
                    uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,message,'Alerta','Icon','warning');
                  
                else
                    %PU Base
                    Sb=1000*app.SbEditField.Value;
                    Vb1=app.Vb1EditField.Value;
                    Vb2=app.Vb2EditField.Value;
                    Ib1=app.Ib1EditField.Value;
                    Ib2=app.Ib2EditField.Value;
                    %Voc
                    app.EditField_Vca.Value=Vca;
                    app.EditField_5.Value=Vb2; 
                    Voc=Vca/Vb2;
                    app.EditField_6.Value=Voc;
                    %Ioc
                    app.EditField_7.Value=Ica;
                    app.EditField_8.Value=Ib2;
                    Ioc=Ica/Ib2;
                    app.EditField_9.Value=Ioc;
                    %Poc
                    app.EditField_10.Value=Pca;
                    app.EditField_11.Value=Sb;
                    Poc=Pca/Sb;
                    app.EditField_12.Value=Poc;
                    %Vsc
                    app.EditField_13.Value=Vcs;
                    app.EditField_14.Value=Vb1;
                    Vsc=Vcs/Vb1;
                    app.EditField_15.Value=Vsc;
                    %Isc
                    app.EditField_16.Value=Ics;
                    app.EditField_17.Value=Ib1;
                    Isc=Ics/Ib1;
                    app.EditField_18.Value=Isc;
                    %Psc
                    app.EditField_19.Value=Pcs;
                    app.EditField_20.Value=Sb;
                    Psc=Pcs/Sb;
                    app.EditField_21.Value=Psc;
                    
                    %Y
                    app.EditField_22.Value=Ioc;
                    app.EditField_23.Value=Voc;
                    Ymag=Ioc/Voc;
                    app.EditField_24.Value=Ymag;
                    %Poc
                    app.EditField_28.Value=Poc;
                    app.EditField_29.Value=Voc;
                    app.EditField_30.Value=Ioc;
                    tetha=-acosd(Poc/(Voc*Ioc));
                    app.EditField_27.Value=tetha;
                    %Y
                    app.EditField_38.Value=Ymag;
                    app.EditField_39.Value=tetha;
                    %Ze
                    app.EditField_31.Value=Vsc;
                    app.EditField_32.Value=Isc;
                    Ze=Vsc/Isc;
                    app.EditField_33.Value=Ze;
                    
                    app.EditField_37.Value=Isc;
                    app.EditField_35.Value=Psc;
                    app.EditField_36.Value=Vsc;
                    ZeTetha=acosd(Psc/(Vsc*Isc));
                    app.EditField_34.Value=ZeTetha;
                    %Z
                    app.EditField_40.Value=Ze;
                    app.EditField_41.Value=ZeTetha;


                    PU=PQ*1/app.SbEditField.Value;
                    PUV=PV*1/Vca;
                    PUIb2=PI*1/Ib2;
                    
                    Iang=acosd(FP);
                    [Ireal,Iimag]=pol2cart(-Iang,PUIb2);
                    I2=complex(Ireal,Iimag);
                    
                    app.EditField_42.Value=PUIb2;
                    app.EditField_43.Value=-Iang;
                    
                    [Zreal,Zimag]=pol2cart(ZeTetha,Ze);
                    ZE=complex(Zreal,Zimag);
                    
%                     [PUVreal,PUVimag]=pol2cart(0,PUV);
%                     puv=complex(PUVreal,PUVimag);
                    
                    V1=(I2*ZE)+1;
                    
                    [V1ang,V1mag] = cart2pol(imag(V1),real(V1));
                    app.V1EditField.Value=V1mag;
                    app.EditField_45.Value=V1ang;
                    
                    R=((V1mag-PUV)/PUV)*100;
                    app.RVEditField.Value=R;
                    
                    n=((FP*PU)/((FP*PU)+(Psc+Poc)))*100;
                    
                    app.EditField_44.Value=n;
                    
                    app.RegulacindeVoltajeGauge.Value=R;
                    app.EficienciaGauge.Value=n;
                
                    
                end
                
        end

        % Value changed function: PotenciaAparenteEditField
        function PotenciaAparenteEditFieldValueChanged(app, event)
            value = app.PotenciaAparenteEditField.Value;
            %PU Base
            app.SbEditField.Value=value;
            app.PotenciaaparenteEditField.Value=value;
            RTb=app.LadoDeBajaEditField.Value;
            if (RTb~=0 )
                Sb=value*1000;
                app.Ib2EditField.Value=(Sb/RTb);
                app.CorrienteEditField_3.Value=(Sb/RTb);    
            end
            
            RTa=app.LadoDeAltaEditField.Value;
            if (RTa~=0)
                Sb=value*1000;
                app.Ib1EditField.Value=(Sb/RTa);
            end
        end

        % Value changed function: LadoDeAltaEditField
        function LadoDeAltaEditFieldValueChanged(app, event)
            RTa = app.LadoDeAltaEditField.Value;
            %PU Base
            if(RTa~=0)
                Sb = app.PotenciaAparenteEditField.Value * 1000;
                app.Ib1EditField.Value=(Sb/RTa);
                app.Vb1EditField.Value=RTa;
            else
                app.Ib1EditField.Value=0;
                app.Vb1EditField.Value=0;
            end
        end

        % Value changed function: LadoDeBajaEditField
        function LadoDeBajaEditFieldValueChanged(app, event)
            RTb = app.LadoDeBajaEditField.Value;
            %PU Base
            if(RTb~=0)
                Sb = app.PotenciaAparenteEditField.Value * 1000;
                app.Ib2EditField.Value=(Sb/RTb);
                app.Vb2EditField.Value=RTb;
                app.CorrienteEditField_3.Value=(Sb/RTb);
                app.VoltajeEditField_3.Value=RTb;
            else
                app.Ib2EditField.Value=0;
                app.Vb2EditField.Value=0;
                app.CorrienteEditField_3.Value=0;
                app.VoltajeEditField_3.Value=0;
            end
        
        end

        % Close request function: 
        % ProgramacinAvanzadaDiegoVillegasGoveaUIFigure
        function ProgramacinAvanzadaDiegoVillegasGoveaUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,'¿Deseas cerrar la aplicación?','Confirmación de cerrar','Icon','question');     
            %uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,selection,'Alerta','Icon','warning');
                  
            if (strcmp(selection,'Cancel'))
            else
                delete(app)
            end
            
        end

        % Menu selected function: DedicatoriaMenu
        function DedicatoriaMenuSelected(app, event)
            message = sprintf('Dedico esta aplicación con mucho cariño, especialmente a Dios que me ha dado la oportunidad y el deseo de poder hacer cada vez mis sueños una realidad, incluyo a personas que han sido la razón de mi existir y a la vez un pilar fundamental en mi desarrollo como profesionista, agradezco a mi familia, a mi persona especial "Cassandra Cerpas Pardo" y por ultimo y no menos importante a mi gran amigo y hermano "Diego Ulises Martínez Aguilar"   ');
            uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,message,'Dedicatoria','Icon','success');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure = uifigure;
            app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure.Name = 'Programación Avanzada - Diego Villegas Govea';
            app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProgramacinAvanzadaDiegoVillegasGoveaUIFigureCloseRequest, true);
            app.InicioMenu = uimenu(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.InicioMenu.Text = 'Inicio';
            app.ResumenMenu = uimenu(app.InicioMenu);
            app.ResumenMenu.MenuSelectedFcn = createCallbackFcn(app, @ResumenMenuSelected, true);
            app.ResumenMenu.Accelerator = 'R';
            app.ResumenMenu.Text = 'Resumen';
            app.AcercadeMenu = uimenu(app.InicioMenu);
            app.AcercadeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeMenuSelected, true);
            app.AcercadeMenu.Accelerator = 'A';
            app.AcercadeMenu.Text = 'Acerca de';
            app.DedicatoriaMenu = uimenu(app.InicioMenu);
            app.DedicatoriaMenu.MenuSelectedFcn = createCallbackFcn(app, @DedicatoriaMenuSelected, true);
            app.DedicatoriaMenu.Accelerator = 'D';
            app.DedicatoriaMenu.Text = 'Dedicatoria';
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel.Text = 'Cálculo de Eficiencia y Regulación de Voltaje de un Transformador Reductor';
            app.PUBasePanel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.PUBasePanel.Title = 'PU Base';
            app.SbEditFieldLabel = uilabel(app.PUBasePanel);
            app.SbEditFieldLabel.Text = 'Sb';
            app.SbEditField = uieditfield(app.PUBasePanel, 'numeric');
            app.SbEditField.Editable = 'off';
            app.Vb1EditFieldLabel = uilabel(app.PUBasePanel);
            app.Vb1EditFieldLabel.Text = 'Vb1';
            app.Vb1EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Vb1EditField.Editable = 'off';
            app.Vb2EditFieldLabel = uilabel(app.PUBasePanel);
            app.Vb2EditFieldLabel.Text = 'Vb2';
            app.Vb2EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Vb2EditField.Editable = 'off';
            app.Ib1EditFieldLabel = uilabel(app.PUBasePanel);
            app.Ib1EditFieldLabel.Text = 'Ib1';
            app.Ib1EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Ib1EditField.Editable = 'off';
            app.Ib2EditFieldLabel = uilabel(app.PUBasePanel);
            app.Ib2EditFieldLabel.Text = 'Ib2';
            app.Ib2EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Ib2EditField.Editable = 'off';
            app.KVALabel_3 = uilabel(app.PUBasePanel);
            app.KVALabel_3.Text = 'KVA';
            app.ALabel_8 = uilabel(app.PUBasePanel);
            app.ALabel_8.Text = 'A';
            app.VLabel_13 = uilabel(app.PUBasePanel);
            app.VLabel_13.Text = 'V';
            app.VLabel_12 = uilabel(app.PUBasePanel);
            app.VLabel_12.Text = 'V';
            app.ALabel_9 = uilabel(app.PUBasePanel);
            app.ALabel_9.Text = 'A';
            app.DATOSDEPLACAPanel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.DATOSDEPLACAPanel.Title = 'DATOS DE PLACA';
            app.RelacindetransformacinLabel = uilabel(app.DATOSDEPLACAPanel);
            app.RelacindetransformacinLabel.Text = 'Relación de transformación:';
            app.EditFieldLabel = uilabel(app.DATOSDEPLACAPanel);
            app.EditFieldLabel.Text = '/';
            app.LadoDeBajaEditField = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.LadoDeBajaEditField.ValueChangedFcn = createCallbackFcn(app, @LadoDeBajaEditFieldValueChanged, true);
            app.PotenciaaparenteEditField_2Label = uilabel(app.DATOSDEPLACAPanel);
            app.PotenciaaparenteEditField_2Label.Text = 'Potencia aparente';
            app.PotenciaAparenteEditField = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.PotenciaAparenteEditField.ValueChangedFcn = createCallbackFcn(app, @PotenciaAparenteEditFieldValueChanged, true);
            app.EditField_3Label = uilabel(app.DATOSDEPLACAPanel);
            app.EditField_3Label.Text = '';
            app.LadoDeAltaEditField = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.LadoDeAltaEditField.ValueChangedFcn = createCallbackFcn(app, @LadoDeAltaEditFieldValueChanged, true);
            app.FrecuenciaEditFieldLabel = uilabel(app.DATOSDEPLACAPanel);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.FrecuenciaEditField.Editable = 'off';
            app.FrecuenciaEditField.Value = 60;
            app.KVALabel = uilabel(app.DATOSDEPLACAPanel);
            app.KVALabel.Text = 'KVA';
            app.HzLabel = uilabel(app.DATOSDEPLACAPanel);
            app.HzLabel.Text = 'Hz';
            app.VLabel_14 = uilabel(app.DATOSDEPLACAPanel);
            app.VLabel_14.Text = 'V';
            app.DATOSDEPRUEBASPanel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.DATOSDEPRUEBASPanel.Title = 'DATOS DE PRUEBAS';
            app.LadodebajaCALabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.LadodebajaCALabel.Text = 'Lado de baja (CA):';
            app.LadodealtaCSLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.LadodealtaCSLabel.Text = 'Lado de alta (CS):';
            app.VoltajeEditFieldLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.VoltajeEditFieldLabel.Text = 'Voltaje';
            app.VoltajeEditField = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.CorrienteEditFieldLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.CorrienteEditFieldLabel.Text = 'Corriente';
            app.CorrienteEditField = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.PotenciaEditFieldLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.PotenciaEditFieldLabel.Text = 'Potencia';
            app.PotenciaEditField = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.CorrienteEditField_2Label = uilabel(app.DATOSDEPRUEBASPanel);
            app.CorrienteEditField_2Label.Text = 'Corriente';
            app.CorrienteEditField_2 = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.VoltajeEditField_2Label = uilabel(app.DATOSDEPRUEBASPanel);
            app.VoltajeEditField_2Label.Text = 'Voltaje';
            app.VoltajeEditField_2 = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.PotenciaEditField_2Label = uilabel(app.DATOSDEPRUEBASPanel);
            app.PotenciaEditField_2Label.Text = 'Potencia';
            app.PotenciaEditField_2 = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.VLabel_5 = uilabel(app.DATOSDEPRUEBASPanel);
            app.VLabel_5.Text = 'V';
            app.ALabel_5 = uilabel(app.DATOSDEPRUEBASPanel);
            app.ALabel_5.Text = 'A';
            app.WLabel_5 = uilabel(app.DATOSDEPRUEBASPanel);
            app.WLabel_5.Text = 'W';
            app.VLabel_6 = uilabel(app.DATOSDEPRUEBASPanel);
            app.VLabel_6.Text = 'V';
            app.ALabel_6 = uilabel(app.DATOSDEPRUEBASPanel);
            app.ALabel_6.Text = 'A';
            app.WLabel_6 = uilabel(app.DATOSDEPRUEBASPanel);
            app.WLabel_6.Text = 'W';
            app.CALCULOSPanel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.CALCULOSPanel.Title = 'CÁLCULOS';
            app.TabGroup = uitabgroup(app.CALCULOSPanel);
            app.VocTab = uitab(app.TabGroup);
            app.VocTab.Title = 'Voc';
            app.VocLabel = uilabel(app.VocTab);
            app.VocLabel.Text = 'Voc = ';
            app.Vb2Label = uilabel(app.VocTab);
            app.Vb2Label.Text = 'Vb2';
            app.VcaLabel_2 = uilabel(app.VocTab);
            app.VcaLabel_2.Text = 'Vca';
            app.Label = uilabel(app.VocTab);
            app.Label.Text = '-----';
            app.VLabel_2 = uilabel(app.VocTab);
            app.VLabel_2.Text = 'V';
            app.VLabel = uilabel(app.VocTab);
            app.VLabel.Text = 'V';
            app.Label_4 = uilabel(app.VocTab);
            app.Label_4.Text = '= ------------------------';
            app.EditField_Vca = uieditfield(app.VocTab, 'numeric');
            app.EditField_Vca.Editable = 'off';
            app.EditField_5 = uieditfield(app.VocTab, 'numeric');
            app.EditField_5.Editable = 'off';
            app.VocLabel_3 = uilabel(app.VocTab);
            app.VocLabel_3.Text = 'Voc = ';
            app.EditField_6 = uieditfield(app.VocTab, 'numeric');
            app.EditField_6.Editable = 'off';
            app.puLabel = uilabel(app.VocTab);
            app.puLabel.Text = 'pu';
            app.IocTab = uitab(app.TabGroup);
            app.IocTab.Title = 'Ioc';
            app.IocLabel = uilabel(app.IocTab);
            app.IocLabel.Text = 'Ioc = ';
            app.Ib2Label = uilabel(app.IocTab);
            app.Ib2Label.Text = 'Ib2';
            app.IcaLabel = uilabel(app.IocTab);
            app.IcaLabel.Text = 'Ica';
            app.Label_6 = uilabel(app.IocTab);
            app.Label_6.Text = '-----';
            app.ALabel = uilabel(app.IocTab);
            app.ALabel.Text = 'A';
            app.ALabel_2 = uilabel(app.IocTab);
            app.ALabel_2.Text = 'A';
            app.Label_7 = uilabel(app.IocTab);
            app.Label_7.Text = '= ------------------------';
            app.EditField_7 = uieditfield(app.IocTab, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.IocTab, 'numeric');
            app.EditField_8.Editable = 'off';
            app.IocLabel_3 = uilabel(app.IocTab);
            app.IocLabel_3.Text = 'Ioc = ';
            app.EditField_9 = uieditfield(app.IocTab, 'numeric');
            app.EditField_9.Editable = 'off';
            app.puLabel_2 = uilabel(app.IocTab);
            app.puLabel_2.Text = 'pu';
            app.PocTab = uitab(app.TabGroup);
            app.PocTab.Title = 'Poc';
            app.PocLabel = uilabel(app.PocTab);
            app.PocLabel.Text = 'Poc = ';
            app.SbLabel = uilabel(app.PocTab);
            app.SbLabel.Text = 'Sb';
            app.PcaLabel = uilabel(app.PocTab);
            app.PcaLabel.Text = 'Pca';
            app.Label_9 = uilabel(app.PocTab);
            app.Label_9.Text = '-----';
            app.WLabel = uilabel(app.PocTab);
            app.WLabel.Text = 'W';
            app.WLabel_2 = uilabel(app.PocTab);
            app.WLabel_2.Text = 'W';
            app.Label_10 = uilabel(app.PocTab);
            app.Label_10.Text = '= ------------------------';
            app.EditField_10 = uieditfield(app.PocTab, 'numeric');
            app.EditField_10.Editable = 'off';
            app.EditField_11 = uieditfield(app.PocTab, 'numeric');
            app.EditField_11.Editable = 'off';
            app.PocLabel_3 = uilabel(app.PocTab);
            app.PocLabel_3.Text = 'Poc = ';
            app.EditField_12 = uieditfield(app.PocTab, 'numeric');
            app.EditField_12.Editable = 'off';
            app.puLabel_3 = uilabel(app.PocTab);
            app.puLabel_3.Text = 'pu';
            app.PerdidasenelncleoLabel = uilabel(app.PocTab);
            app.PerdidasenelncleoLabel.Text = 'Perdidas en el núcleo';
            app.VscTab = uitab(app.TabGroup);
            app.VscTab.Title = 'Vsc';
            app.VscLabel = uilabel(app.VscTab);
            app.VscLabel.Text = 'Vsc = ';
            app.Vb1Label = uilabel(app.VscTab);
            app.Vb1Label.Text = 'Vb1';
            app.VcsLabel = uilabel(app.VscTab);
            app.VcsLabel.Text = 'Vcs';
            app.Label_12 = uilabel(app.VscTab);
            app.Label_12.Text = '-----';
            app.VLabel_3 = uilabel(app.VscTab);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.VscTab);
            app.VLabel_4.Text = 'V';
            app.Label_13 = uilabel(app.VscTab);
            app.Label_13.Text = '= ------------------------';
            app.EditField_13 = uieditfield(app.VscTab, 'numeric');
            app.EditField_13.Editable = 'off';
            app.EditField_14 = uieditfield(app.VscTab, 'numeric');
            app.EditField_14.Editable = 'off';
            app.VscLabel_3 = uilabel(app.VscTab);
            app.VscLabel_3.Text = 'Vsc = ';
            app.EditField_15 = uieditfield(app.VscTab, 'numeric');
            app.EditField_15.Editable = 'off';
            app.puLabel_4 = uilabel(app.VscTab);
            app.puLabel_4.Text = 'pu';
            app.IscTab = uitab(app.TabGroup);
            app.IscTab.Title = 'Isc';
            app.IscLabel = uilabel(app.IscTab);
            app.IscLabel.Text = 'Isc = ';
            app.Ib1Label = uilabel(app.IscTab);
            app.Ib1Label.Text = 'Ib1';
            app.IcsLabel = uilabel(app.IscTab);
            app.IcsLabel.Text = 'Ics';
            app.Label_15 = uilabel(app.IscTab);
            app.Label_15.Text = '-----';
            app.ALabel_3 = uilabel(app.IscTab);
            app.ALabel_3.Text = 'A';
            app.ALabel_4 = uilabel(app.IscTab);
            app.ALabel_4.Text = 'A';
            app.Label_16 = uilabel(app.IscTab);
            app.Label_16.Text = '= ------------------------';
            app.EditField_16 = uieditfield(app.IscTab, 'numeric');
            app.EditField_16.Editable = 'off';
            app.EditField_17 = uieditfield(app.IscTab, 'numeric');
            app.EditField_17.Editable = 'off';
            app.IscLabel_3 = uilabel(app.IscTab);
            app.IscLabel_3.Text = 'Isc = ';
            app.EditField_18 = uieditfield(app.IscTab, 'numeric');
            app.EditField_18.Editable = 'off';
            app.puLabel_5 = uilabel(app.IscTab);
            app.puLabel_5.Text = 'pu';
            app.PscTab = uitab(app.TabGroup);
            app.PscTab.Title = 'Psc';
            app.PscLabel = uilabel(app.PscTab);
            app.PscLabel.Text = 'Psc =';
            app.SbLabel_2 = uilabel(app.PscTab);
            app.SbLabel_2.Text = 'Sb';
            app.PcsLabel = uilabel(app.PscTab);
            app.PcsLabel.Text = 'Pcs';
            app.Label_18 = uilabel(app.PscTab);
            app.Label_18.Text = '-----';
            app.WLabel_3 = uilabel(app.PscTab);
            app.WLabel_3.Text = 'W';
            app.WLabel_4 = uilabel(app.PscTab);
            app.WLabel_4.Text = 'W';
            app.Label_19 = uilabel(app.PscTab);
            app.Label_19.Text = '= ------------------------';
            app.EditField_19 = uieditfield(app.PscTab, 'numeric');
            app.EditField_19.Editable = 'off';
            app.EditField_20 = uieditfield(app.PscTab, 'numeric');
            app.EditField_20.Editable = 'off';
            app.PscLabel_2 = uilabel(app.PscTab);
            app.PscLabel_2.Text = 'Psc = ';
            app.EditField_21 = uieditfield(app.PscTab, 'numeric');
            app.EditField_21.Editable = 'off';
            app.puLabel_6 = uilabel(app.PscTab);
            app.puLabel_6.Text = 'pu';
            app.PerdidasdelconductorLabel = uilabel(app.PscTab);
            app.PerdidasdelconductorLabel.Text = 'Perdidas del conductor';
            app.YTab = uitab(app.TabGroup);
            app.YTab.Title = 'Yθ';
            app.YLabel = uilabel(app.YTab);
            app.YLabel.Text = 'Yθ = ';
            app.VocLabel_2 = uilabel(app.YTab);
            app.VocLabel_2.Text = 'Voc';
            app.IocLabel_2 = uilabel(app.YTab);
            app.IocLabel_2.Text = 'Ioc';
            app.Label_21 = uilabel(app.YTab);
            app.Label_21.Text = '-----';
            app.Label_24 = uilabel(app.YTab);
            app.Label_24.Text = '';
            app.Label_25 = uilabel(app.YTab);
            app.Label_25.Text = '';
            app.Label_22 = uilabel(app.YTab);
            app.Label_22.Text = '= ------------------------';
            app.EditField_22 = uieditfield(app.YTab, 'numeric');
            app.EditField_22.Editable = 'off';
            app.EditField_23 = uieditfield(app.YTab, 'numeric');
            app.EditField_23.Editable = 'off';
            app.YLabel_2 = uilabel(app.YTab);
            app.YLabel_2.Text = 'Yθ = ';
            app.EditField_24 = uieditfield(app.YTab, 'numeric');
            app.EditField_24.Editable = 'off';
            app.puLabel_7 = uilabel(app.YTab);
            app.puLabel_7.Text = 'pu';
            app.PocTab_2 = uitab(app.TabGroup);
            app.PocTab_2.Title = 'Poc θ';
            app.PocVocIoccosLabel = uilabel(app.PocTab_2);
            app.PocVocIoccosLabel.Text = 'Poc = Voc * Ioc * cos(θ)';
            app.PocLabel_2 = uilabel(app.PocTab_2);
            app.PocLabel_2.Text = 'Poc';
            app.VocIocLabel = uilabel(app.PocTab_2);
            app.VocIocLabel.Text = 'Voc * Ioc';
            app.cos1Label = uilabel(app.PocTab_2);
            app.cos1Label.Text = 'θ = -cos^-1(-------------)';
            app.Label_28 = uilabel(app.PocTab_2);
            app.Label_28.Text = 'θ = ';
            app.EditField_27 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_27.Editable = 'off';
            app.Label_30 = uilabel(app.PocTab_2);
            app.Label_30.Text = '°';
            app.cos1Label_2 = uilabel(app.PocTab_2);
            app.cos1Label_2.Text = 'θ = -cos^-1(--------------)';
            app.EditField_28 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_28.Editable = 'off';
            app.EditField_29 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_29.Editable = 'off';
            app.Label_29 = uilabel(app.PocTab_2);
            app.Label_29.Text = '*';
            app.EditField_30 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_30.Editable = 'off';
            app.YTab_2 = uitab(app.TabGroup);
            app.YTab_2.Title = 'Y';
            app.YLabel_3 = uilabel(app.YTab_2);
            app.YLabel_3.Text = 'Y = ';
            app.EditField_38 = uieditfield(app.YTab_2, 'numeric');
            app.EditField_38.Editable = 'off';
            app.LLabel = uilabel(app.YTab_2);
            app.LLabel.Text = 'L';
            app.EditField_39 = uieditfield(app.YTab_2, 'numeric');
            app.EditField_39.Editable = 'off';
            app.Label_38 = uilabel(app.YTab_2);
            app.Label_38.Text = '°';
            app.ZeTab = uitab(app.TabGroup);
            app.ZeTab.Title = 'Ze';
            app.ZeLabel = uilabel(app.ZeTab);
            app.ZeLabel.Text = 'Ze = ';
            app.IscLabel_2 = uilabel(app.ZeTab);
            app.IscLabel_2.Text = 'Isc';
            app.VscLabel_2 = uilabel(app.ZeTab);
            app.VscLabel_2.Text = 'Vsc';
            app.Label_31 = uilabel(app.ZeTab);
            app.Label_31.Text = '-----';
            app.Label_32 = uilabel(app.ZeTab);
            app.Label_32.Text = '= -----------------------';
            app.EditField_31 = uieditfield(app.ZeTab, 'numeric');
            app.EditField_31.Editable = 'off';
            app.EditField_32 = uieditfield(app.ZeTab, 'numeric');
            app.EditField_32.Editable = 'off';
            app.ZeLabel_2 = uilabel(app.ZeTab);
            app.ZeLabel_2.Text = 'Ze = ';
            app.EditField_33 = uieditfield(app.ZeTab, 'numeric');
            app.EditField_33.Editable = 'off';
            app.puLabel_8 = uilabel(app.ZeTab);
            app.puLabel_8.Text = 'pu';
            app.ZeTab_2 = uitab(app.TabGroup);
            app.ZeTab_2.Title = 'Ze θ';
            app.PscVscIsccosLabel = uilabel(app.ZeTab_2);
            app.PscVscIsccosLabel.Text = 'Psc = Vsc * Isc * cos(θ)';
            app.PscLabel_3 = uilabel(app.ZeTab_2);
            app.PscLabel_3.Text = 'Psc';
            app.VscIscLabel = uilabel(app.ZeTab_2);
            app.VscIscLabel.Text = 'Vsc * Isc';
            app.cos1Label_3 = uilabel(app.ZeTab_2);
            app.cos1Label_3.Text = 'θ = cos^-1(-------------)';
            app.Label_33 = uilabel(app.ZeTab_2);
            app.Label_33.Text = 'θ = ';
            app.EditField_34 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_34.Editable = 'off';
            app.Label_34 = uilabel(app.ZeTab_2);
            app.Label_34.Text = '°';
            app.cos1Label_4 = uilabel(app.ZeTab_2);
            app.cos1Label_4.Text = 'θ = cos^-1(---------------)';
            app.EditField_35 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_35.Editable = 'off';
            app.EditField_36 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_36.Editable = 'off';
            app.Label_35 = uilabel(app.ZeTab_2);
            app.Label_35.Text = '*';
            app.EditField_37 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_37.Editable = 'off';
            app.ZTab = uitab(app.TabGroup);
            app.ZTab.Title = 'Z';
            app.ZLabel = uilabel(app.ZTab);
            app.ZLabel.Text = 'Z = ';
            app.EditField_40 = uieditfield(app.ZTab, 'numeric');
            app.EditField_40.Editable = 'off';
            app.LLabel_2 = uilabel(app.ZTab);
            app.LLabel_2.Text = 'L';
            app.EditField_41 = uieditfield(app.ZTab, 'numeric');
            app.EditField_41.Editable = 'off';
            app.Label_39 = uilabel(app.ZTab);
            app.Label_39.Text = '°';
            app.PLENACARGAPanel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.PLENACARGAPanel.Title = 'PLENA CARGA';
            app.PotenciaaparenteEditFieldLabel = uilabel(app.PLENACARGAPanel);
            app.PotenciaaparenteEditFieldLabel.Text = 'Potencia aparente';
            app.PotenciaaparenteEditField = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.PotenciaaparenteEditField.Editable = 'off';
            app.FactordepotenciaEditFieldLabel = uilabel(app.PLENACARGAPanel);
            app.FactordepotenciaEditFieldLabel.Text = 'Factor de potencia';
            app.FactordepotenciaEditField = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.VoltajeEditField_3Label = uilabel(app.PLENACARGAPanel);
            app.VoltajeEditField_3Label.Text = 'Voltaje';
            app.VoltajeEditField_3 = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.VoltajeEditField_3.Editable = 'off';
            app.CorrienteEditField_3Label = uilabel(app.PLENACARGAPanel);
            app.CorrienteEditField_3Label.Text = 'Corriente';
            app.CorrienteEditField_3 = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.CorrienteEditField_3.Editable = 'off';
            app.KVALabel_2 = uilabel(app.PLENACARGAPanel);
            app.KVALabel_2.Text = 'KVA';
            app.Label_40 = uilabel(app.PLENACARGAPanel);
            app.Label_40.Text = '';
            app.VLabel_9 = uilabel(app.PLENACARGAPanel);
            app.VLabel_9.Text = 'V';
            app.ALabel_7 = uilabel(app.PLENACARGAPanel);
            app.ALabel_7.Text = 'A';
            app.Label_51 = uilabel(app.PLENACARGAPanel);
            app.Label_51.Text = '(-)';
            app.CONTROLESButtonGroup = uibuttongroup(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.CONTROLESButtonGroup.Title = 'CONTROLES';
            app.LimpiarCamposSwitchLabel = uilabel(app.CONTROLESButtonGroup);
            app.LimpiarCamposSwitchLabel.Text = 'Limpiar Campos';
            app.LimpiarCamposSwitch = uiswitch(app.CONTROLESButtonGroup, 'rocker');
            app.LimpiarCamposSwitch.ValueChangedFcn = createCallbackFcn(app, @LimpiarCamposSwitchValueChanged, true);
            app.CalcularButton = uibutton(app.CONTROLESButtonGroup, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.EFICIENCIAPanel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.EFICIENCIAPanel.Title = 'EFICIENCIA';
            app.Label_41 = uilabel(app.EFICIENCIAPanel);
            app.Label_41.Text = '';
            app.Label_44 = uilabel(app.EFICIENCIAPanel);
            app.Label_44.Text = '%';
            app.EditField_44Label = uilabel(app.EFICIENCIAPanel);
            app.EditField_44Label.Text = 'η =';
            app.EditField_44 = uieditfield(app.EFICIENCIAPanel, 'numeric');
            app.EditField_44.Editable = 'off';
            app.SFPLabel = uilabel(app.EFICIENCIAPanel);
            app.SFPLabel.Text = 'S * FP';
            app.SFPPcobrePncleoLabel = uilabel(app.EFICIENCIAPanel);
            app.SFPPcobrePncleoLabel.Text = '(S*FP)+(Pcobre+Pnúcleo)';
            app.Label_45 = uilabel(app.EFICIENCIAPanel);
            app.Label_45.Text = 'η = --------------------- *100 ';
            app.Label_46 = uilabel(app.EFICIENCIAPanel);
            app.Label_46.Text = ')';
            app.Label_47 = uilabel(app.EFICIENCIAPanel);
            app.Label_47.Text = '(';
            app.REGULACINDEVOLTAJEPanel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.REGULACINDEVOLTAJEPanel.Title = 'REGULACIÓN DE VOLTAJE';
            app.V1I2ZV2Label = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.V1I2ZV2Label.Text = 'V1 = I2 * Z + V2';
            app.I2Label = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.I2Label.Text = 'I2 = ';
            app.EditField_42 = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.EditField_42.Editable = 'off';
            app.LLabel_3 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.LLabel_3.Text = 'L';
            app.EditField_43 = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.EditField_43.Editable = 'off';
            app.TomandoV2Label = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.TomandoV2Label.Text = 'Tomando V2';
            app.V1EditFieldLabel = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.V1EditFieldLabel.Text = 'V1 =';
            app.V1EditField = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.V1EditField.Editable = 'off';
            app.RVEditFieldLabel = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.RVEditFieldLabel.Text = 'RV =';
            app.RVEditField = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.RVEditField.Editable = 'off';
            app.Label_43 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.Label_43.Text = '%';
            app.VLabel_11 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.VLabel_11.Text = '° V';
            app.LLabel_4 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.LLabel_4.Text = 'L';
            app.EditField_45 = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.EditField_45.Editable = 'off';
            app.ALabel_10 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.ALabel_10.Text = '° A';
            app.RegulacindeVoltajeLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.RegulacindeVoltajeLabel.Text = 'Regulación de Voltaje (%)';
            app.RegulacindeVoltajeGauge = uigauge(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure, 'semicircular');
            app.RegulacindeVoltajeGauge.Limits = [0 10];
            app.RegulacindeVoltajeGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.EficienciaLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.EficienciaLabel.Text = 'Eficiencia (%)';
            app.EficienciaGauge = uigauge(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure, 'semicircular');
            app.EficienciaGauge.Limits = [50 100];
            app.EficienciaGauge.MajorTicks = [50 55 60 65 70 75 80 85 90 95 100];
            app.Label_48 = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.Label_48.Text = '';
            app.Label_49 = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.Label_49.Text = '';
            app.Label_50 = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.Label_50.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Transformadores

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure)
        end
    end
end