classdef MedidorArmonicos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProgramacinAvanzadaDiegoVillegasGoveaUIFigure  matlab.ui.Figure
        MedidordeArmnicosLabel      matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        InyeccindeArmnicosPanel     matlab.ui.container.Panel
        TercerarmnicoCheckBox       matlab.ui.control.CheckBox
        QuintoarmnicoCheckBox       matlab.ui.control.CheckBox
        SptimoarmnicoCheckBox       matlab.ui.control.CheckBox
        OnceavoarmnicoCheckBox      matlab.ui.control.CheckBox
        FundamentalCheckBox         matlab.ui.control.CheckBox
        NdeCiclosSpinnerLabel       matlab.ui.control.Label
        NdeCiclosSpinner            matlab.ui.control.Spinner
        FundamentalPanel            matlab.ui.container.Panel
        AmplitudEditFieldLabel      matlab.ui.control.Label
        AmplitudEditField           matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel    matlab.ui.control.Label
        FrecuenciaEditField         matlab.ui.control.NumericEditField
        VLabel_2                    matlab.ui.control.Label
        HzLabel_6                   matlab.ui.control.Label
        AmplituddelosArmnicosPanel  matlab.ui.container.Panel
        erarmnicoEditFieldLabel     matlab.ui.control.Label
        erarmnicoEditField          matlab.ui.control.NumericEditField
        toarmnicoEditFieldLabel     matlab.ui.control.Label
        toarmnicoEditField          matlab.ui.control.NumericEditField
        moarmnicoEditFieldLabel     matlab.ui.control.Label
        moarmnicoEditField          matlab.ui.control.NumericEditField
        voarmnicoEditFieldLabel     matlab.ui.control.Label
        voarmnicoEditField          matlab.ui.control.NumericEditField
        VLabel_3                    matlab.ui.control.Label
        VLabel_4                    matlab.ui.control.Label
        VLabel_5                    matlab.ui.control.Label
        VLabel_6                    matlab.ui.control.Label
        THDPanel                    matlab.ui.container.Panel
        EditField                   matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        CONTROLESButtonGroup        matlab.ui.container.ButtonGroup
        CalcularButton              matlab.ui.control.Button
        LimpiarCamposSwitchLabel    matlab.ui.control.Label
        LimpiarCamposSwitch         matlab.ui.control.Switch
        FundamentalLampLabel        matlab.ui.control.Label
        FundamentalLamp             matlab.ui.control.Lamp
        moLampLabel                 matlab.ui.control.Label
        moLamp                      matlab.ui.control.Lamp
        voLampLabel                 matlab.ui.control.Label
        voLamp                      matlab.ui.control.Lamp
        toLampLabel                 matlab.ui.control.Label
        toLamp                      matlab.ui.control.Lamp
        roLampLabel                 matlab.ui.control.Label
        roLamp                      matlab.ui.control.Lamp
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        ResultanteLampLabel         matlab.ui.control.Label
        ResultanteLamp              matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Callback function
        function FundamentalCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function TercerarmnicoCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function QuintoarmnicoCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function SptimoarmnicoCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function OnceavoarmnicoCheckBoxValueChanged(app, event)
            
        end

        % Callback function: CalcularButton, FundamentalCheckBox, 
        % NdeCiclosSpinner, OnceavoarmnicoCheckBox, 
        % QuintoarmnicoCheckBox, SptimoarmnicoCheckBox, 
        % TercerarmnicoCheckBox
        function CalcularButtonPushed(app, event)
            valueF = app.FundamentalCheckBox.Value;
            if valueF
                app.FundamentalLamp.Color = [1 0 0] ;
                
            else
                app.FundamentalLamp.Color = [0.94 0.94 0.94] ;
            end 
            
            value3 = app.TercerarmnicoCheckBox.Value;
            if value3
                app.roLamp.Color = [0 1 0] ;
                app.erarmnicoEditField.Enable='on';
            else
                app.roLamp.Color = [0.94 0.94 0.94] ;
                app.erarmnicoEditField.Enable='off';
            end 
            
            value5 = app.QuintoarmnicoCheckBox.Value;
            if value5
                app.toLamp.Color = [0 0 1] ;
                app.toarmnicoEditField.Enable='on';
            else
                app.toLamp.Color = [0.94 0.94 0.94] ;
                app.toarmnicoEditField.Enable='off';
            end 
            
            value7 = app.SptimoarmnicoCheckBox.Value;
            if value7
                app.moLamp.Color = [1 0 1] ;
                app.moarmnicoEditField.Enable='on';
            else
                app.moLamp.Color = [0.94 0.94 0.94] ;
                app.moarmnicoEditField.Enable='off';
            end
            
            value11 = app.OnceavoarmnicoCheckBox.Value;
            if value11
                app.voLamp.Color = [0 1 1] ;
                app.voarmnicoEditField.Enable='on';
            else
                app.voLamp.Color = [0.94 0.94 0.94] ;
                app.voarmnicoEditField.Enable='off';
            end
            
            Vm=app.AmplitudEditField.Value;
            f=app.FrecuenciaEditField.Value;
            
            if (Vm==0 ||f==0)
                message = sprintf('Revise que los campos de amplitud y frecuencia no sean 0.');
                uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,message,'Alerta','Icon','warning');
            else      
                Vm3=app.erarmnicoEditField.Value;
                Vm5=app.toarmnicoEditField.Value;
                Vm7=app.moarmnicoEditField.Value;
                Vm11=app.voarmnicoEditField.Value;
                
                N=app.NdeCiclosSpinner.Value;
                
                t=linspace(0,(1/60)*N,200);
                
                Fm=Vm*sin(2*pi*f*t);
                Fm3=Vm3*sin(2*pi*(f*3)*t);
                Fm5=Vm5*sin(2*pi*(f*5)*t);
                Fm7=Vm7*sin(2*pi*(f*7)*t);
                Fm11=Vm11*sin(2*pi*(f*11)*t);
                
                Ft=Fm+Fm3+Fm5+Fm7+Fm11;
                
                thd_t=(sqrt((Vm3^2)+(Vm5^2)+(Vm7^2)+(Vm11^2))/(Vm))*100;
                
                app.EditField.Value=thd_t;
                
                x1=[1 3 5 7 11];
                y1=[(Vm/Vm)*100 (Vm3/Vm)*100 (Vm5/Vm)*100 (Vm7/Vm)*100 (Vm11/Vm)*100];
                
                bar(app.UIAxes4,x1,y1,'FaceColor',[0 .6 .6],'EdgeColor',[0 0.8 0.8],'LineWidth',1.0);
                
                Once = app.OnceavoarmnicoCheckBox.Value;
                Siete = app.SptimoarmnicoCheckBox.Value;
                Quinto = app.QuintoarmnicoCheckBox.Value;
                Tercer = app.TercerarmnicoCheckBox.Value;
                Fundamental = app.FundamentalCheckBox.Value;
                
                %app.UIAxes3.LineWidth=1.5;
                %app.UIAxes4.LineWidth=1.5;
                plot(app.UIAxes3,t,Ft,'-y','LineWidth',1.5);
                
                y=Ft;
                y(2,:)=Fm;
                y(3,:)=Fm3;
                y(4,:)=Fm5;
                y(5,:)=Fm7;
                y(6,:)=Fm11;
                
                x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                
                x(1,:).Color=[1 1 0];
                
                
                if Fundamental
                    x(2,:).Color=[1 0 0];
                else
                    x(2,:).Color=[1 0 0];
                    y(2,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                    
                if Tercer
                    x(3,:).Color=[0 1 0];
                else
                    x(3,:).Color=[0 1 0];
                    y(3,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                
                if Quinto
                    x(4,:).Color=[0 0 1];
                else 
                    x(4,:).Color=[0 0 1];
                    y(4,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                
                if Siete
                    x(5,:).Color=[1 0 1];
                else
                    x(5,:).Color=[1 0 1];
                    y(5,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                
                if Once
                    x(6,:).Color=[0 1 1];
                else
                    x(6,:).Color=[0 1 1];
                    y(6,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
%                 if(Fundamental==true && Tercer~=true && Quinto~=true && Siete~=true &&  Once~=true)
%                     plot(app.UIAxes3,t,Ft,'-y',t,Fm,'-r','LineWidth',1.5);
%                 elseif (Fundamental==true && Tercer==true && Quinto~=true && Siete~=true &&  Once~=true)
%                     plot(app.UIAxes3,t,Ft,'-y',t,Fm,'-r',t,Fm3,'-g','LineWidth',1.5);
%                 elseif (Fundamental==true && Tercer==true && Quinto==true && Siete~=true &&  Once~=true)
%                     plot(app.UIAxes3,t,Ft,'-y',t,Fm,'-r',t,Fm3,'-g',t,Fm5,'-b','LineWidth',1.5);   
%                 elseif (Fundamental==true && Tercer==true && Quinto==true && Siete==true &&  Once~=true)
%                     plot(app.UIAxes3,t,Ft,'-y',t,Fm,'-r',t,Fm3,'-g',t,Fm5,'-b',t,Fm7,'-m','LineWidth',1.5);
%                 elseif (Fundamental==true && Tercer==true && Quinto==true && Siete==true &&  Once==true)
%                     plot(app.UIAxes3,t,Ft,'-y',t,Fm,'-r',t,Fm3,'-g',t,Fm5,'-b',t,Fm7,'-m',t,Fm11,'-c','LineWidth',1.5);     
%                 end
                
            end
        end

        % Value changed function: LimpiarCamposSwitch
        function LimpiarCamposSwitchValueChanged(app, event)
            value = app.LimpiarCamposSwitch.Value;
            switch value
                case 'On'
                    f = waitbar(0,'Espere un momento por favor...');
                    pause(.1)
                    app.AmplitudEditField.Value=0;
                    app.FrecuenciaEditField.Value=0;
                    
                    
                    waitbar(.2,f,'Limpiando campos');
                    pause(0.1)
                    app.TercerarmnicoCheckBox.Value=false;
                    app.QuintoarmnicoCheckBox.Value=false;
                    app.SptimoarmnicoCheckBox.Value=false;
                    app.OnceavoarmnicoCheckBox.Value=false;
                    app.FundamentalCheckBox.Value=false;
                    app.NdeCiclosSpinner.Value=1;
                    
                    waitbar(.4,f,'Limpiando campos');
                    pause(0.1)
                    app.erarmnicoEditField.Value=0;
                    app.toarmnicoEditField.Value=0;
                    app.moarmnicoEditField.Value=0;
                    app.voarmnicoEditField.Value=0;
                    
                    waitbar(.6,f,'Limpiando campos');
                    pause(0.05)
                    app.EditField.Value=0;
                    
                    waitbar(.8,f,'Finalizando');
                    pause(0.1)
                    cla(app.UIAxes3)
                    cla(app.UIAxes4)
                    
                    waitbar(1,f,'Finalizando');
                    pause(.05)
                    app.moLamp.Color = [0.94 0.94 0.94] ;
                    app.voLamp.Color = [0.94 0.94 0.94] ;
                    app.toLamp.Color = [0.94 0.94 0.94] ;
                    app.roLamp.Color = [0.94 0.94 0.94] ;
                    app.FundamentalLamp.Color = [0.94 0.94 0.94] ;
                    close(f)   
                    
                    app.LimpiarCamposSwitch.Value = 'Off';
                    
            end        
        end

        % Close request function: 
        % ProgramacinAvanzadaDiegoVillegasGoveaUIFigure
        function ProgramacinAvanzadaDiegoVillegasGoveaUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,'¿Deseas cerrar la aplicación?','Confirmación de cerrar','Icon','question');     
            %uialert(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure,selection,'Alerta','Icon','warning');
                  
            if (strcmp(selection,'Cancel'))
            else
                delete(app)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure = uifigure;
            app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure.Name = 'Programación Avanzada - Diego Villegas Govea';
            app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProgramacinAvanzadaDiegoVillegasGoveaUIFigureCloseRequest, true);
            app.MedidordeArmnicosLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.MedidordeArmnicosLabel.Text = 'Medidor de Armónicos';
            app.Label = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.Label.Text = '';
            app.Panel = uipanel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.InyeccindeArmnicosPanel = uipanel(app.Panel);
            app.InyeccindeArmnicosPanel.Title = 'Inyección de Armónicos';
            app.TercerarmnicoCheckBox = uicheckbox(app.InyeccindeArmnicosPanel);
            app.TercerarmnicoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.TercerarmnicoCheckBox.Text = 'Tercer armónico.';
            app.TercerarmnicoCheckBox.Value = true;
            app.QuintoarmnicoCheckBox = uicheckbox(app.InyeccindeArmnicosPanel);
            app.QuintoarmnicoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.QuintoarmnicoCheckBox.Text = 'Quinto armónico.';
            app.QuintoarmnicoCheckBox.Value = true;
            app.SptimoarmnicoCheckBox = uicheckbox(app.InyeccindeArmnicosPanel);
            app.SptimoarmnicoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.SptimoarmnicoCheckBox.Text = 'Séptimo armónico.';
            app.SptimoarmnicoCheckBox.Value = true;
            app.OnceavoarmnicoCheckBox = uicheckbox(app.InyeccindeArmnicosPanel);
            app.OnceavoarmnicoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.OnceavoarmnicoCheckBox.Text = 'Onceavo armónico.';
            app.OnceavoarmnicoCheckBox.Value = true;
            app.FundamentalCheckBox = uicheckbox(app.InyeccindeArmnicosPanel);
            app.FundamentalCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.FundamentalCheckBox.Text = 'Fundamental';
            app.FundamentalCheckBox.Value = true;
            app.NdeCiclosSpinnerLabel = uilabel(app.InyeccindeArmnicosPanel);
            app.NdeCiclosSpinnerLabel.Text = 'N° de Ciclos';
            app.NdeCiclosSpinner = uispinner(app.InyeccindeArmnicosPanel);
            app.NdeCiclosSpinner.Limits = [1 Inf];
            app.NdeCiclosSpinner.RoundFractionalValues = 'on';
            app.NdeCiclosSpinner.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.NdeCiclosSpinner.Value = 1;
            app.FundamentalPanel = uipanel(app.Panel);
            app.FundamentalPanel.Title = 'Fundamental';
            app.AmplitudEditFieldLabel = uilabel(app.FundamentalPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.FundamentalPanel, 'numeric');
            app.FrecuenciaEditFieldLabel = uilabel(app.FundamentalPanel);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.FundamentalPanel, 'numeric');
            app.VLabel_2 = uilabel(app.FundamentalPanel);
            app.VLabel_2.Text = 'V';
            app.HzLabel_6 = uilabel(app.FundamentalPanel);
            app.HzLabel_6.Text = 'Hz';
            app.AmplituddelosArmnicosPanel = uipanel(app.Panel);
            app.AmplituddelosArmnicosPanel.Title = 'Amplitud de los Armónicos';
            app.erarmnicoEditFieldLabel = uilabel(app.AmplituddelosArmnicosPanel);
            app.erarmnicoEditFieldLabel.Text = '3er armónico';
            app.erarmnicoEditField = uieditfield(app.AmplituddelosArmnicosPanel, 'numeric');
            app.toarmnicoEditFieldLabel = uilabel(app.AmplituddelosArmnicosPanel);
            app.toarmnicoEditFieldLabel.Text = '5to armónico';
            app.toarmnicoEditField = uieditfield(app.AmplituddelosArmnicosPanel, 'numeric');
            app.moarmnicoEditFieldLabel = uilabel(app.AmplituddelosArmnicosPanel);
            app.moarmnicoEditFieldLabel.Text = '7mo armónico';
            app.moarmnicoEditField = uieditfield(app.AmplituddelosArmnicosPanel, 'numeric');
            app.voarmnicoEditFieldLabel = uilabel(app.AmplituddelosArmnicosPanel);
            app.voarmnicoEditFieldLabel.Text = '11vo armónico';
            app.voarmnicoEditField = uieditfield(app.AmplituddelosArmnicosPanel, 'numeric');
            app.VLabel_3 = uilabel(app.AmplituddelosArmnicosPanel);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.AmplituddelosArmnicosPanel);
            app.VLabel_4.Text = 'V';
            app.VLabel_5 = uilabel(app.AmplituddelosArmnicosPanel);
            app.VLabel_5.Text = 'V';
            app.VLabel_6 = uilabel(app.AmplituddelosArmnicosPanel);
            app.VLabel_6.Text = 'V';
            app.THDPanel = uipanel(app.Panel);
            app.THDPanel.Title = 'THD';
            app.EditField = uieditfield(app.THDPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.Label_3 = uilabel(app.THDPanel);
            app.Label_3.Text = '%';
            app.CONTROLESButtonGroup = uibuttongroup(app.Panel);
            app.CONTROLESButtonGroup.Title = 'CONTROLES';
            app.CalcularButton = uibutton(app.CONTROLESButtonGroup, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarCamposSwitchLabel = uilabel(app.CONTROLESButtonGroup);
            app.LimpiarCamposSwitchLabel.Text = 'Limpiar Campos';
            app.LimpiarCamposSwitch = uiswitch(app.CONTROLESButtonGroup, 'slider');
            app.LimpiarCamposSwitch.ValueChangedFcn = createCallbackFcn(app, @LimpiarCamposSwitchValueChanged, true);
            app.FundamentalLampLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.FundamentalLampLabel.Text = 'Fundamental';
            app.FundamentalLamp = uilamp(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.moLampLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.moLampLabel.Text = '7mo';
            app.moLamp = uilamp(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.voLampLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.voLampLabel.Text = '11vo';
            app.voLamp = uilamp(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.toLampLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.toLampLabel.Text = '5to';
            app.toLamp = uilamp(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.roLampLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.roLampLabel.Text = '3ro';
            app.roLamp = uilamp(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.UIAxes3 = uiaxes(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            title(app.UIAxes3, 'Composición de Armónicos')
            xlabel(app.UIAxes3, 'Tiempo (seg)')
            ylabel(app.UIAxes3, 'Amplitud (V)')
            app.UIAxes4 = uiaxes(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            title(app.UIAxes4, 'Distorsión Armonica Total')
            xlabel(app.UIAxes4, 'Orden del Armónico')
            ylabel(app.UIAxes4, 'Magnitud en % de la Fundamental')
            app.ResultanteLampLabel = uilabel(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
            app.ResultanteLampLabel.Text = 'Resultante';
            app.ResultanteLamp = uilamp(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MedidorArmonicos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProgramacinAvanzadaDiegoVillegasGoveaUIFigure)
        end
    end
end