classdef SistemasElectricosDePotencia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SistemasElctricosdePotenciaUIFigure  matlab.ui.Figure
        InicioMenu                      matlab.ui.container.Menu
        ResumenMenu                     matlab.ui.container.Menu
        AcercadeMenu                    matlab.ui.container.Menu
        DedicatoriaMenu                 matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        InformacindelSistemaTab         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        DIVISINDEESTUDIOSPROFESIONALESLabel  matlab.ui.control.Label
        INSTITUTOTECNOLGICODEMORELIALabel  matlab.ui.control.Label
        APPDESIGNERLabel                matlab.ui.control.Label
        MATLABR2017bTheMathWorksIncLabel  matlab.ui.control.Label
        DIEGOULISESMARTNEZAGUILARLabel  matlab.ui.control.Label
        NdeControl15121185Label         matlab.ui.control.Label
        DEPARTAMENTODEINGENIERAELCTRICALabel  matlab.ui.control.Label
        MORELIAMICHOACNMXICOLabel       matlab.ui.control.Label
        DRJOSLUISMONROYMORALESLabel     matlab.ui.control.Label
        ASESORLabel                     matlab.ui.control.Label
        ENERO2019Label                  matlab.ui.control.Label
        MedidordeArmnicos_2             matlab.ui.control.Label
        ClculoyCompensacindeunSistemaElctricodeACLabel_2  matlab.ui.control.Label
        ClculodeEficienciayRegulacindeunTransformadorReductorLabel_2  matlab.ui.control.Label
        MedidordeArmnicos_3             matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ClculodeEficienciayRegulacindeunTransformadorReductorLabel_3  matlab.ui.control.Label
        ButtonArmonicos_2               matlab.ui.control.Button
        ButtonCompensacion_2            matlab.ui.control.Button
        ButtonTransformador_2           matlab.ui.control.Button
        AppTransformadoresLabel         matlab.ui.control.Label
        AppCompensacinLabel             matlab.ui.control.Label
        AppArmnicosLabel                matlab.ui.control.Label
        ClculodeEficienciayRegulacindeunTransformadorReductorLabel_4  matlab.ui.control.Label
        ClculodeEficienciayRegulacindeunTransformadorReductorLabel_5  matlab.ui.control.Label
        AppTransformadoresTab           matlab.ui.container.Tab
        imgTransformador                matlab.ui.control.Button
        ButtonTransformador             matlab.ui.control.Button
        ClculodeEficienciayRegulacindeunTransformadorReductorLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PanelTransformadores            matlab.ui.container.Panel
        DATOSDEPRUEBASPanel             matlab.ui.container.Panel
        LadodebajaCALabel               matlab.ui.control.Label
        LadodealtaCSLabel               matlab.ui.control.Label
        VoltajeEditFieldLabel           matlab.ui.control.Label
        VoltajeEditField                matlab.ui.control.NumericEditField
        CorrienteEditFieldLabel         matlab.ui.control.Label
        CorrienteEditField              matlab.ui.control.NumericEditField
        PotenciaEditFieldLabel          matlab.ui.control.Label
        PotenciaEditField               matlab.ui.control.NumericEditField
        CorrienteEditField_2Label       matlab.ui.control.Label
        CorrienteEditField_2            matlab.ui.control.NumericEditField
        VoltajeEditField_2Label         matlab.ui.control.Label
        VoltajeEditField_2              matlab.ui.control.NumericEditField
        PotenciaEditField_2Label        matlab.ui.control.Label
        PotenciaEditField_2             matlab.ui.control.NumericEditField
        VLabel_14                       matlab.ui.control.Label
        ALabel_3                        matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        VLabel_15                       matlab.ui.control.Label
        ALabel_4                        matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        DATOSDEPLACAPanel               matlab.ui.container.Panel
        RelacindetransformacinLabel     matlab.ui.control.Label
        EditFieldLabel                  matlab.ui.control.Label
        LadoDeBajaEditField             matlab.ui.control.NumericEditField
        PotenciaaparenteEditField_2Label  matlab.ui.control.Label
        PotenciaAparenteEditField       matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        LadoDeAltaEditField             matlab.ui.control.NumericEditField
        FrecuenciaEditField_3Label      matlab.ui.control.Label
        FrecuenciaEditField_3           matlab.ui.control.NumericEditField
        KVALabel_2                      matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        VLabel_13                       matlab.ui.control.Label
        CALCULOSPanel                   matlab.ui.container.Panel
        TabGroup_2                      matlab.ui.container.TabGroup
        VocTab                          matlab.ui.container.Tab
        VocLabel                        matlab.ui.control.Label
        Vb2Label                        matlab.ui.control.Label
        VcaLabel                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        VLabel_16                       matlab.ui.control.Label
        VLabel_17                       matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        EditField_Vca                   matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        VocLabel_2                      matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        puLabel                         matlab.ui.control.Label
        IocTab                          matlab.ui.container.Tab
        IocLabel                        matlab.ui.control.Label
        Ib2Label                        matlab.ui.control.Label
        IcaLabel                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        ALabel_5                        matlab.ui.control.Label
        ALabel_6                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        IocLabel_2                      matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        puLabel_2                       matlab.ui.control.Label
        PocTab                          matlab.ui.container.Tab
        PocLabel                        matlab.ui.control.Label
        SbLabel                         matlab.ui.control.Label
        PcaLabel                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        WLabel_3                        matlab.ui.control.Label
        WLabel_4                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        PocLabel_2                      matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        puLabel_3                       matlab.ui.control.Label
        PerdidasenelncleoLabel          matlab.ui.control.Label
        VscTab                          matlab.ui.container.Tab
        VscLabel                        matlab.ui.control.Label
        Vb1Label                        matlab.ui.control.Label
        VcsLabel                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        VLabel_18                       matlab.ui.control.Label
        VLabel_19                       matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        VscLabel_2                      matlab.ui.control.Label
        EditField_14                    matlab.ui.control.NumericEditField
        puLabel_4                       matlab.ui.control.Label
        IscTab                          matlab.ui.container.Tab
        IscLabel                        matlab.ui.control.Label
        Ib1Label                        matlab.ui.control.Label
        IcsLabel                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        ALabel_7                        matlab.ui.control.Label
        ALabel_8                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        IscLabel_2                      matlab.ui.control.Label
        EditField_17                    matlab.ui.control.NumericEditField
        puLabel_5                       matlab.ui.control.Label
        PscTab                          matlab.ui.container.Tab
        PscLabel                        matlab.ui.control.Label
        SbLabel_2                       matlab.ui.control.Label
        PcsLabel                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        WLabel_5                        matlab.ui.control.Label
        WLabel_6                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        PscLabel_2                      matlab.ui.control.Label
        EditField_20                    matlab.ui.control.NumericEditField
        puLabel_6                       matlab.ui.control.Label
        PerdidasdelconductorLabel       matlab.ui.control.Label
        YTab                            matlab.ui.container.Tab
        YLabel                          matlab.ui.control.Label
        VocLabel_3                      matlab.ui.control.Label
        IocLabel_3                      matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        EditField_23                    matlab.ui.control.NumericEditField
        puLabel_7                       matlab.ui.control.Label
        PocTab_2                        matlab.ui.container.Tab
        PocVocIoccosLabel               matlab.ui.control.Label
        PocLabel_3                      matlab.ui.control.Label
        VocIocLabel                     matlab.ui.control.Label
        cos1Label                       matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        EditField_24                    matlab.ui.control.NumericEditField
        Label_34                        matlab.ui.control.Label
        cos1Label_2                     matlab.ui.control.Label
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        Label_35                        matlab.ui.control.Label
        EditField_27                    matlab.ui.control.NumericEditField
        YTab_2                          matlab.ui.container.Tab
        YLabel_3                        matlab.ui.control.Label
        EditField_28                    matlab.ui.control.NumericEditField
        LLabel                          matlab.ui.control.Label
        EditField_29                    matlab.ui.control.NumericEditField
        Label_36                        matlab.ui.control.Label
        ZeTab                           matlab.ui.container.Tab
        ZeLabel                         matlab.ui.control.Label
        IscLabel_3                      matlab.ui.control.Label
        VscLabel_3                      matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        EditField_30                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        ZeLabel_2                       matlab.ui.control.Label
        EditField_32                    matlab.ui.control.NumericEditField
        puLabel_8                       matlab.ui.control.Label
        ZeTab_2                         matlab.ui.container.Tab
        PscVscIsccosLabel               matlab.ui.control.Label
        PscLabel_3                      matlab.ui.control.Label
        VscIscLabel                     matlab.ui.control.Label
        cos1Label_3                     matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        EditField_33                    matlab.ui.control.NumericEditField
        Label_40                        matlab.ui.control.Label
        cos1Label_4                     matlab.ui.control.Label
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        Label_41                        matlab.ui.control.Label
        EditField_36                    matlab.ui.control.NumericEditField
        ZTab                            matlab.ui.container.Tab
        ZLabel                          matlab.ui.control.Label
        EditField_37                    matlab.ui.control.NumericEditField
        LLabel_2                        matlab.ui.control.Label
        EditField_38                    matlab.ui.control.NumericEditField
        Label_42                        matlab.ui.control.Label
        CONTROLESButtonGroup_3          matlab.ui.container.ButtonGroup
        LimpiarCamposSwitch_3Label      matlab.ui.control.Label
        LimpiarCamposSwitch_3           matlab.ui.control.RockerSwitch
        CalcularButton_3                matlab.ui.control.Button
        EFICIENCIAPanel                 matlab.ui.container.Panel
        Label_45                        matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        EditField_39Label               matlab.ui.control.Label
        EditField_39                    matlab.ui.control.NumericEditField
        SFPLabel                        matlab.ui.control.Label
        SFPPcobrePncleoLabel            matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        Label_48                        matlab.ui.control.Label
        Label_49                        matlab.ui.control.Label
        REGULACINDEVOLTAJEPanel         matlab.ui.container.Panel
        V1I2ZV2Label                    matlab.ui.control.Label
        I2Label                         matlab.ui.control.Label
        EditField_40                    matlab.ui.control.NumericEditField
        LLabel_3                        matlab.ui.control.Label
        EditField_41                    matlab.ui.control.NumericEditField
        TomandoV2Label                  matlab.ui.control.Label
        V1EditFieldLabel                matlab.ui.control.Label
        V1EditField                     matlab.ui.control.NumericEditField
        RVEditFieldLabel                matlab.ui.control.Label
        RVEditField                     matlab.ui.control.NumericEditField
        Label_50                        matlab.ui.control.Label
        VLabel_21                       matlab.ui.control.Label
        LLabel_4                        matlab.ui.control.Label
        EditField_42                    matlab.ui.control.NumericEditField
        ALabel_10                       matlab.ui.control.Label
        PLENACARGAPanel                 matlab.ui.container.Panel
        PotenciaaparenteEditFieldLabel  matlab.ui.control.Label
        PotenciaaparenteEditField       matlab.ui.control.NumericEditField
        FactordepotenciaEditFieldLabel  matlab.ui.control.Label
        FactordepotenciaEditField       matlab.ui.control.NumericEditField
        VoltajeEditField_3Label         matlab.ui.control.Label
        VoltajeEditField_3              matlab.ui.control.NumericEditField
        CorrienteEditField_3Label       matlab.ui.control.Label
        CorrienteEditField_3            matlab.ui.control.NumericEditField
        KVALabel_3                      matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        VLabel_20                       matlab.ui.control.Label
        ALabel_9                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        RegulacindeVoltajeGaugeLabel    matlab.ui.control.Label
        RegulacindeVoltajeGauge         matlab.ui.control.SemicircularGauge
        EficienciaGaugeLabel            matlab.ui.control.Label
        EficienciaGauge                 matlab.ui.control.SemicircularGauge
        PUBasePanel                     matlab.ui.container.Panel
        SbEditFieldLabel                matlab.ui.control.Label
        SbEditField                     matlab.ui.control.NumericEditField
        Vb1EditFieldLabel               matlab.ui.control.Label
        Vb1EditField                    matlab.ui.control.NumericEditField
        Vb2EditFieldLabel               matlab.ui.control.Label
        Vb2EditField                    matlab.ui.control.NumericEditField
        Ib1EditFieldLabel               matlab.ui.control.Label
        Ib1EditField                    matlab.ui.control.NumericEditField
        Ib2EditFieldLabel               matlab.ui.control.Label
        Ib2EditField                    matlab.ui.control.NumericEditField
        KVALabel                        matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        VLabel_11                       matlab.ui.control.Label
        VLabel_12                       matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        AppCompensacinTab               matlab.ui.container.Tab
        imgCompensacion                 matlab.ui.control.Button
        ButtonCompensacion              matlab.ui.control.Button
        ClculoyCompensacindeunSistemaElctricodeACLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PanelCompensacion               matlab.ui.container.Panel
        DatosdeentradaalsistemaPanel    matlab.ui.container.Panel
        TipoDropDownLabel               matlab.ui.control.Label
        TipoDropDown                    matlab.ui.control.DropDown
        FrecuenciaHzEditFieldLabel      matlab.ui.control.Label
        FrecuenciaHzEditField           matlab.ui.control.NumericEditField
        VoltajeVEditFieldLabel          matlab.ui.control.Label
        VoltajeVEditField               matlab.ui.control.NumericEditField
        nguloEditFieldLabel             matlab.ui.control.Label
        nguloEditField                  matlab.ui.control.NumericEditField
        ResistenciaEditFieldLabel       matlab.ui.control.Label
        ResistenciaEditField            matlab.ui.control.NumericEditField
        InductorButtonGroup             matlab.ui.container.ButtonGroup
        InductanciamHButton             matlab.ui.control.RadioButton
        ReactanciaButton                matlab.ui.control.RadioButton
        EditField_43                    matlab.ui.control.NumericEditField
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        CONTROLESPanel                  matlab.ui.container.Panel
        LimpiarCamposSwitch_5Label      matlab.ui.control.Label
        LimpiarCamposSwitch_5           matlab.ui.control.Switch
        CompensarButton                 matlab.ui.control.Button
        CalcularButton_4                matlab.ui.control.Button
        UIAxes_7                        matlab.ui.control.UIAxes
        PotenciaAparenteVAGaugeLabel    matlab.ui.control.Label
        PotenciaAparenteVAGauge         matlab.ui.control.Gauge
        PotenciaActivaWGaugeLabel       matlab.ui.control.Label
        PotenciaActivaWGauge            matlab.ui.control.Gauge
        PotenciaReactivaVARGaugeLabel   matlab.ui.control.Label
        PotenciaReactivaVARGauge        matlab.ui.control.Gauge
        ResultadosPanel                 matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        magI1EditField                  matlab.ui.control.NumericEditField
        angI1EditField                  matlab.ui.control.NumericEditField
        AppArmnicosTab                  matlab.ui.container.Tab
        imgArmonicos                    matlab.ui.control.Button
        ButtonArmonicos                 matlab.ui.control.Button
        MedidordeArmnicos               matlab.ui.control.Label
        MedidordeArmnicos_4             matlab.ui.control.Label
        PanelArmonicos                  matlab.ui.container.Panel
        Label_6                         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        FundamentalPanel_2              matlab.ui.container.Panel
        AmplitudEditField_2Label        matlab.ui.control.Label
        AmplitudEditField_2             matlab.ui.control.NumericEditField
        FrecuenciaEditField_2Label      matlab.ui.control.Label
        FrecuenciaEditField_2           matlab.ui.control.NumericEditField
        VLabel_6                        matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        AmplituddelosArmnicosPanel_2    matlab.ui.container.Panel
        erarmnicoEditField_2Label       matlab.ui.control.Label
        erarmnicoEditField_2            matlab.ui.control.NumericEditField
        toarmnicoEditField_2Label       matlab.ui.control.Label
        toarmnicoEditField_2            matlab.ui.control.NumericEditField
        moarmnicoEditField_2Label       matlab.ui.control.Label
        moarmnicoEditField_2            matlab.ui.control.NumericEditField
        voarmnicoEditField_2Label       matlab.ui.control.Label
        voarmnicoEditField_2            matlab.ui.control.NumericEditField
        VLabel_7                        matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        VLabel_10                       matlab.ui.control.Label
        THDPanel_2                      matlab.ui.container.Panel
        EditField_3                     matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        CONTROLESButtonGroup_2          matlab.ui.container.ButtonGroup
        CalcularButton_2                matlab.ui.control.Button
        LimpiarCamposSwitch_2Label      matlab.ui.control.Label
        LimpiarCamposSwitch_2           matlab.ui.control.Switch
        InyeccindeArmnicosPanel_2       matlab.ui.container.Panel
        TercerarmnicoCheckBox_2         matlab.ui.control.CheckBox
        QuintoarmnicoCheckBox_2         matlab.ui.control.CheckBox
        SptimoarmnicoCheckBox_2         matlab.ui.control.CheckBox
        OnceavoarmnicoCheckBox_2        matlab.ui.control.CheckBox
        FundamentalCheckBox_2           matlab.ui.control.CheckBox
        NdeCiclosSpinner_2Label         matlab.ui.control.Label
        NdeCiclosSpinner_2              matlab.ui.control.Spinner
        UIAxes4                         matlab.ui.control.UIAxes
        FundamentalLampLabel            matlab.ui.control.Label
        FundamentalLamp                 matlab.ui.control.Lamp
        moLampLabel                     matlab.ui.control.Label
        moLamp                          matlab.ui.control.Lamp
        voLampLabel                     matlab.ui.control.Label
        voLamp                          matlab.ui.control.Lamp
        toLampLabel                     matlab.ui.control.Label
        toLamp                          matlab.ui.control.Lamp
        roLampLabel                     matlab.ui.control.Label
        roLamp                          matlab.ui.control.Lamp
        ResultanteLampLabel             matlab.ui.control.Label
        ResultanteLamp                  matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Iconos
            imshow('Dato1.png','parent',app.UIAxes);
            imshow('Dato2.png','parent',app.UIAxes_2);
            imshow('Dato3.png','parent',app.UIAxes_3);
            imshow('Dato4.png','parent',app.UIAxes_4);
            imshow('Dato5.png','parent',app.UIAxes_5);
            imshow('Dato6.png','parent',app.UIAxes_6);
                 
            %Visibilidad
            app.PanelArmonicos.Visible='off';
            app.PanelCompensacion.Visible='off';
            app.PanelTransformadores.Visible='off';
            
            %App. Compensación
            app.angI1EditField.Visible = 'off';
            app.magI1EditField.Visible='off';
        end

        % Button pushed function: ButtonArmonicos
        function ButtonArmonicosPushed(app, event)
            app.imgArmonicos.Visible = 'off';
            app.ButtonArmonicos.Visible = 'off';
            
            app.PanelArmonicos.Visible='on';
        end

        % Button pushed function: ButtonTransformador
        function ButtonTransformadorPushed(app, event)
            app.imgTransformador.Visible = 'off';
            app.ButtonTransformador.Visible = 'off';
            
            app.PanelTransformadores.Visible='on';
        end

        % Button pushed function: ButtonCompensacion
        function ButtonCompensacionPushed(app, event)
            app.imgCompensacion.Visible = 'off';
            app.ButtonCompensacion.Visible = 'off';
            
            app.PanelCompensacion.Visible='on';
        end

        % Button pushed function: ButtonTransformador_2
        function ButtonTransformador_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.AppTransformadoresTab;
        end

        % Button pushed function: ButtonArmonicos_2
        function ButtonArmonicos_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.AppArmnicosTab;
        end

        % Button pushed function: ButtonCompensacion_2
        function ButtonCompensacion_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.AppCompensacinTab;
        end

        % Close request function: SistemasElctricosdePotenciaUIFigure
        function SistemasElctricosdePotenciaUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.SistemasElctricosdePotenciaUIFigure,'¿Deseas cerrar la aplicación?','Confirmación de cerrar','Icon','question');     
                  
            if (strcmp(selection,'Cancel'))
            else
                delete(app)
            end
        end

        % Callback function: CalcularButton_2, FundamentalCheckBox_2, 
        % NdeCiclosSpinner_2, OnceavoarmnicoCheckBox_2, 
        % QuintoarmnicoCheckBox_2, SptimoarmnicoCheckBox_2, 
        % TercerarmnicoCheckBox_2
        function CalcularButton_2Pushed(app, event)
            valueF = app.FundamentalCheckBox_2.Value;
            if valueF
                app.FundamentalLamp.Color = [1 0 0] ;
                
            else
                app.FundamentalLamp.Color = [0.94 0.94 0.94] ;
            end 
            
            value3 = app.TercerarmnicoCheckBox_2.Value;
            if value3
                app.roLamp.Color = [0 1 0] ;
                app.erarmnicoEditField_2.Enable='on';
            else
                app.roLamp.Color = [0.94 0.94 0.94] ;
                app.erarmnicoEditField_2.Enable='off';
            end 
            
            value5 = app.QuintoarmnicoCheckBox_2.Value;
            if value5
                app.toLamp.Color = [0 0 1] ;
                app.toarmnicoEditField_2.Enable='on';
            else
                app.toLamp.Color = [0.94 0.94 0.94] ;
                app.toarmnicoEditField_2.Enable='off';
            end 
            
            value7 = app.SptimoarmnicoCheckBox_2.Value;
            if value7
                app.moLamp.Color = [1 0 1] ;
                app.moarmnicoEditField_2.Enable='on';
            else
                app.moLamp.Color = [0.94 0.94 0.94] ;
                app.moarmnicoEditField_2.Enable='off';
            end
            
            value11 = app.OnceavoarmnicoCheckBox_2.Value;
            if value11
                app.voLamp.Color = [0 1 1] ;
                app.voarmnicoEditField_2.Enable='on';
            else
                app.voLamp.Color = [0.94 0.94 0.94] ;
                app.voarmnicoEditField_2.Enable='off';
            end
            
            Vm=app.AmplitudEditField_2.Value;
            f=app.FrecuenciaEditField_2.Value;
            
            if (Vm==0 ||f==0)
                message = sprintf('Revise que los campos de amplitud y frecuencia no sean 0.');
                uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Alerta','Icon','warning');
            else      
                Vm3=app.erarmnicoEditField_2.Value;
                Vm5=app.toarmnicoEditField_2.Value;
                Vm7=app.moarmnicoEditField_2.Value;
                Vm11=app.voarmnicoEditField_2.Value;
                
                N=app.NdeCiclosSpinner_2.Value;
                
                t=linspace(0,(1/60)*N,200);
                
                Fm=Vm*sin(2*pi*f*t);
                Fm3=Vm3*sin(2*pi*(f*3)*t);
                Fm5=Vm5*sin(2*pi*(f*5)*t);
                Fm7=Vm7*sin(2*pi*(f*7)*t);
                Fm11=Vm11*sin(2*pi*(f*11)*t);
                
                Ft=Fm+Fm3+Fm5+Fm7+Fm11;
                
                thd_t=(sqrt((Vm3^2)+(Vm5^2)+(Vm7^2)+(Vm11^2))/(Vm))*100;
                
                app.EditField_3.Value=thd_t;
                
                x1=[1 3 5 7 11];
                
                ybarra=(Vm/Vm)*100;
                ybarra(2,:)=(Vm3/Vm)*100;
                ybarra(3,:)=(Vm5/Vm)*100;
                ybarra(4,:)=(Vm7/Vm)*100;
                ybarra(5,:)=(Vm11/Vm)*100;
                
                %ybarra=[(Vm/Vm)*100 (Vm3/Vm)*100 (Vm5/Vm)*100 (Vm7/Vm)*100 (Vm11/Vm)*100];
                
                bar(app.UIAxes4,x1,ybarra,'FaceColor',[0 .6 .6],'EdgeColor',[0 0.8 0.8],'LineWidth',1.0);
                
                Once = app.OnceavoarmnicoCheckBox_2.Value;
                Siete = app.SptimoarmnicoCheckBox_2.Value;
                Quinto = app.QuintoarmnicoCheckBox_2.Value;
                Tercer = app.TercerarmnicoCheckBox_2.Value;
                Fundamental = app.FundamentalCheckBox_2.Value;
                
                if Fundamental
                else
                    ybarra(1,:)=0;
                    bar(app.UIAxes4,x1,ybarra,'FaceColor',[0 .6 .6],'EdgeColor',[0 0.8 0.8],'LineWidth',1.0);
                end
                
                if Tercer
                else
                    ybarra(2,:)=0;
                    bar(app.UIAxes4,x1,ybarra,'FaceColor',[0 .6 .6],'EdgeColor',[0 0.8 0.8],'LineWidth',1.0);
                end
                
                if Quinto
                else
                    ybarra(3,:)=0;
                    bar(app.UIAxes4,x1,ybarra,'FaceColor',[0 .6 .6],'EdgeColor',[0 0.8 0.8],'LineWidth',1.0);
                end
                
                if Siete
                else
                    ybarra(4,:)=0;
                    bar(app.UIAxes4,x1,ybarra,'FaceColor',[0 .6 .6],'EdgeColor',[0 0.8 0.8],'LineWidth',1.0);
                end
                
                if Once
                else
                    ybarra(5,:)=0;
                    bar(app.UIAxes4,x1,ybarra,'FaceColor',[0 .6 .6],'EdgeColor',[0 0.8 0.8],'LineWidth',1.0);
                end
                
                %app.UIAxes3.LineWidth=1.5;
                %app.UIAxes4.LineWidth=1.5;
                plot(app.UIAxes3,t,Ft,'-y','LineWidth',1.5);
                
                y=Ft;
                y(2,:)=Fm;
                y(3,:)=Fm3;
                y(4,:)=Fm5;
                y(5,:)=Fm7;
                y(6,:)=Fm11;
                
                x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                
                x(1,:).Color=[1 1 0];
                
                
                if Fundamental
                    x(2,:).Color=[1 0 0];
                else
                    x(2,:).Color=[1 0 0];
                    y(2,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                    
                if Tercer
                    x(3,:).Color=[0 1 0];
                else
                    x(3,:).Color=[0 1 0];
                    y(3,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                
                if Quinto
                    x(4,:).Color=[0 0 1];
                else 
                    x(4,:).Color=[0 0 1];
                    y(4,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                
                if Siete
                    x(5,:).Color=[1 0 1];
                else
                    x(5,:).Color=[1 0 1];
                    y(5,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                
                if Once
                    x(6,:).Color=[0 1 1];
                else
                    x(6,:).Color=[0 1 1];
                    y(6,:)=0;
                    x=plot(app.UIAxes3,t,y,'LineWidth',1.5);
                    x(1,:).Color=[1 1 0];
                    
                    if Fundamental
                        x(2,:).Color=[1 0 0];
                    end
                        
                    if Tercer
                        x(3,:).Color=[0 1 0];
                    end
                    
                    if Quinto
                        x(4,:).Color=[0 0 1];
                    end
                    
                    if Siete
                        x(5,:).Color=[1 0 1];
                    end
                    
                    if Once
                        x(6,:).Color=[0 1 1];
                    end
                end
                
            end
        end

        % Value changed function: LimpiarCamposSwitch_2
        function LimpiarCamposSwitch_2ValueChanged(app, event)
            value = app.LimpiarCamposSwitch_2.Value;
            
            switch value
                case 'On'
                    f = waitbar(0,'Espere un momento por favor...');
                    pause(.1)
                    app.AmplitudEditField_2.Value=0;
                    app.FrecuenciaEditField_2.Value=0;
                    
                    
                    waitbar(.2,f,'Limpiando campos');
                    pause(0.1)
                    app.TercerarmnicoCheckBox_2.Value=false;
                    app.QuintoarmnicoCheckBox_2.Value=false;
                    app.SptimoarmnicoCheckBox_2.Value=false;
                    app.OnceavoarmnicoCheckBox_2.Value=false;
                    app.FundamentalCheckBox_2.Value=false;
                    app.NdeCiclosSpinner_2.Value=1;
                    
                    waitbar(.4,f,'Limpiando campos');
                    pause(0.1)
                    app.erarmnicoEditField_2.Value=0;
                    app.toarmnicoEditField_2.Value=0;
                    app.moarmnicoEditField_2.Value=0;
                    app.voarmnicoEditField_2.Value=0;
                    
                    waitbar(.6,f,'Limpiando campos');
                    pause(0.05)
                    app.EditField_3.Value=0;
                    
                    waitbar(.8,f,'Finalizando');
                    pause(0.1)
                    cla(app.UIAxes3)
                    cla(app.UIAxes4)
                    
                    waitbar(1,f,'Finalizando');
                    pause(.05)
                    app.moLamp.Color = [0.94 0.94 0.94] ;
                    app.voLamp.Color = [0.94 0.94 0.94] ;
                    app.toLamp.Color = [0.94 0.94 0.94] ;
                    app.roLamp.Color = [0.94 0.94 0.94] ;
                    app.FundamentalLamp.Color = [0.94 0.94 0.94] ;
                    close(f)   
                    
                    app.LimpiarCamposSwitch_2.Value = 'Off';
                    
            end
        end

        % Value changed function: TipoDropDown
        function TipoDropDownValueChanged(app, event)
            value = app.TipoDropDown.Value;
            switch value
                case 'Circuitos RL serie'
                    app.InductorButtonGroup.Title = 'Inductor';
                    app.InductanciamHButton.Text= 'Inductancia (mH)';
                case 'Circuitos RC serie'
                    app.InductorButtonGroup.Title = 'Capacitor';
                    app.InductanciamHButton.Text= 'Capacitancia (uF)';
            end
        end

        % Value changed function: LimpiarCamposSwitch_5
        function LimpiarCamposSwitch_5ValueChanged(app, event)
            value = app.LimpiarCamposSwitch_5.Value;
            switch value
                case 'On'
                    f = waitbar(0,'Espere un momento por favor...');
                    pause(.1)
                    app.FrecuenciaHzEditField.Value=0;
                    app.VoltajeVEditField.Value=0;
                    app.nguloEditField.Value=0;
                    app.ResistenciaEditField.Value=0;
                    
                    
                    waitbar(.2,f,'Limpiando campos');
                    pause(0.1)
                    app.EditField_43.Value=0;
                    
                    
                    waitbar(.4,f,'Limpiando campos');
                    pause(0.05)
                    app.Slider.Value=100;
                    
                    waitbar(.6,f,'Limpiando Axes');
                    pause(0.1)
                    
                    cla(app.UIAxes_7)
                    %cla reset
                    cla(app.UIAxes_7,'reset')
                    
                    waitbar(.8,f,'Limpiando Gauge');
                    pause(0.1)
                    app.PotenciaAparenteVAGauge.Value=0;
                    app.PotenciaActivaWGauge.Value=0;
                    app.PotenciaReactivaVARGauge.Value=0;
                    
                    
                    waitbar(1,f,'Finalizando');
                    pause(.1)
                    app.Slider.Enable='off';
                    app.ListBox.Items = {''};
                    close(f)   
                    
                    app.LimpiarCamposSwitch_5.Value = 'Off';
                    
            end 
        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)
            app.Slider.Value=100;
            f=app.FrecuenciaHzEditField.Value;

            V1=app.VoltajeVEditField.Value;
            angV1=app.nguloEditField.Value;
            
            R1=app.ResistenciaEditField.Value;
            
            X=app.EditField_43.Value;
            
            if (f==0||V1==0||R1==0||X==0)
                    
                    message = sprintf('Datos ingresados incorrectamente, revise que no existan campos vacíos.');
                    uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Alerta','Icon','warning');
                  
            else
                
                inductor=app.InductanciamHButton.Value;
                reactancia=app.ReactanciaButton.Value;
                            
                if(reactancia)
                    Xreac=app.EditField_43.Value;
                else
                    Xvi=app.EditField_43.Value;    
                end
                
                %IMPEDANCIA
                value = app.TipoDropDown.Value;
                switch value
                    case 'Circuitos RL serie'
                        if(reactancia)
                            Z1=complex(R1,Xreac);
                        else
                            %Circuito RL
                            Xvi=Xvi*10^-3;
                            XL=2*pi*f*Xvi;
                            Z1=complex(R1,XL);    
                        end
                    case 'Circuitos RC serie'
                        if(reactancia)
                            Z1=complex(R1,-Xreac);
                        else
                            %Circuito RC
                            c=Xvi*10^-6;
                            Xc=1/(2*pi*f*c);
                            Z1=complex(R1,-Xc);    
                        end
                end
        
                [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
                
                res1=['V1 = ' num2str(complex(realV1,imagV1))];
                res2=['V1 = ' num2str(V1) ' |_ ' num2str(angV1) '° V'];
            
                
                res3=['Z1 = ' num2str(Z1)];
                [angZ1,magZ1] = cart2pol(real(Z1),imag(Z1));
                angZ1=angZ1*180/pi;
                
                res4=['Z1 = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
            
                res5=['La impedancia equivalente es: ' num2str(Z1)];
                res6=['Zeq = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
                
                %CORRIENTE
                [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
            
                I1=complex(realV1, imagV1)/Z1;
                res7=['I1 = ' num2str(I1)];
                [angI1,magI1] = cart2pol(real(I1),imag(I1));
                angI1=angI1*180/pi;
                
                res8=['I1 = ' num2str(magI1) ' |_ ' num2str(angI1) '° A'];
                
            
                %POTENCIA APARENTE
                S=V1*magI1;
                app.PotenciaAparenteVAGauge.Value=S;
                res9=['La potencia aparente es: ' num2str(S) ' VA'];
            
                %POTENCIA ACTIVA
                P=S*cos(angI1*pi/180);
                app.PotenciaActivaWGauge.Value=P;
                res10=['La potencia activa es: ' num2str(P) ' W'];
                
                %POTENCIA REACTIVA
                Q=S*sin(angI1*pi/180);
                app.PotenciaReactivaVARGauge.Value=Q;
                if Q<0
                    res11=['La potencia reactiva es: ' num2str(-Q) ' VAR (atraso)'];
                elseif Q>0 
                    res11=['La potencia reactiva es: ' num2str(Q) ' VAR (adelanto)'];
                else
                    res11=['La potencia reactiva es: ' num2str(Q) ' VAR'];
                end
            
                %FACTOR DE POTENCIA
                FP=P/S;
                res12=['El factor de potencia es: ' num2str(FP)];
            
                %SEÑALES SENOIDALES
                w=2*pi*f;
                if(f==0)
                    f=60;
                    t=0:0.0001:(1/f)*3;
                else
                    t=0:0.0001:(1/f)*3;
                end
                
                
                app.angI1EditField.Value=angI1;
                app.magI1EditField.Value=magI1;
                
                Vm=V1*cos(w*t+angV1*pi/180);
                
                Im=magI1*cos(w*t+angI1*pi/180);
                
                %GRÁFICOS
                %Gráfica de comparación de voltaje vs corriente
                yyaxis(app.UIAxes_7,'left');
                plot(app.UIAxes_7,t,Vm,'LineWidth',2,'Color', [0.27, 0.50, 0.70 ]);
                title(app.UIAxes_7,'Sistema Eléctrico No Compensado');
                
                ylabel(app.UIAxes_7,'Voltaje (V)','LineWidth',2,'Color', [0.27, 0.50, 0.70 ]); 
                
                yyaxis(app.UIAxes_7,'right');
                plot(app.UIAxes_7,t,Im,'LineWidth',2,'Color', [0.69, 0.13 0.13]);
                
                
                xlabel(app.UIAxes_7,'Tiempo (seg)'); 
                ylabel(app.UIAxes_7,'Corriente (A)','LineWidth',2,'Color', [0.69, 0.13 0.13]);
    
                V=['V = ' num2str(V1) ' V'];
                I=['I = ' num2str(round(magI1,2)) ' A'];
                
                legend(app.UIAxes_7,V, I);
                
                app.UIAxes_7.FontName = 'Century Gothic';
                app.UIAxes_7.FontWeight = 'bold';
                app.UIAxes_7.Box = 'on';
                app.UIAxes_7.XGrid = 'on';
                app.UIAxes_7.YGrid = 'on';
                
                app.Slider.Enable='off';
                
                
                %ListBox
                datos1 = {'Sistema Eléctrico No Compensado';'';mat2str(res1);''; mat2str(res2);''; mat2str(res3);''; mat2str(res4);''; mat2str(res5);''; mat2str(res6);''; mat2str(res7);''; mat2str(res8);''; mat2str(res9);''; mat2str(res10);''; mat2str(res11);''; mat2str(res12)};
                app.ListBox.Items = datos1;
            
            end
        end

        % Button pushed function: CompensarButton
        function CompensarButtonPushed(app, event)
            app.Slider.Value=100;
            f=app.FrecuenciaHzEditField.Value;

            V1=app.VoltajeVEditField.Value;
            angV1=app.nguloEditField.Value;
            
            R1=app.ResistenciaEditField.Value;
            
            X=app.EditField_43.Value;
            
            if (f==0||V1==0||R1==0||X==0)
                    
                    message = sprintf('Datos ingresados incorrectamente, revise que no existan campos vacíos.');
                    uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Alerta','Icon','warning');
                  
            else
                    inductor=app.InductanciamHButton.Value;
                    reactancia=app.ReactanciaButton.Value;
                                
                    if(reactancia)
                        Xreac=app.EditField_43.Value;
                    else
                        Xvi=app.EditField_43.Value;    
                    end
                    
                    
                    %IMPEDANCIA
                    value = app.TipoDropDown.Value;
                    switch value
                        case 'Circuitos RL serie'
                            if(reactancia)
                                Z1=complex(R1,Xreac);
                            else
                                %Circuito RL
                                Xvi=Xvi*10^-3;
                                XL=2*pi*f*Xvi;
                                Z1=complex(R1,XL);    
                            end
                        case 'Circuitos RC serie'
                            if(reactancia)
                                Z1=complex(R1,-Xreac);
                            else
                                %Circuito RC
                                c=Xvi*10^-6;
                                Xc=1/(2*pi*f*c);
                                Z1=complex(R1,-Xc);    
                            end
                    end
            
                    [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
                    
                    res1=['V1 = ' num2str(complex(realV1,imagV1))];
                    res2=['V1 = ' num2str(V1) ' |_ ' num2str(angV1) '° V'];
                
                    
                    res3=['Z1 = ' num2str(Z1)];
                    [angZ1,magZ1] = cart2pol(real(Z1),imag(Z1));
                    angZ1=angZ1*180/pi;
                    
                    res4=['Z1 = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
                
                    res5=['La impedancia equivalente es: ' num2str(Z1)];
                    res6=['Zeq = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
                    
                    %CORRIENTE
                    [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
                
                    I1=complex(realV1, imagV1)/Z1;
                    res7=['I1 = ' num2str(I1)];
                    [angI1,magI1] = cart2pol(real(I1),imag(I1));
                    angI1=angI1*180/pi;
                    
                    res8=['I1 = ' num2str(magI1) ' |_ ' num2str(angI1) '° A'];
                    
                
                    %POTENCIA APARENTE
                    S=V1*magI1;
                    app.PotenciaAparenteVAGauge.Value=S;
                    res9=['La potencia aparente es: ' num2str(S) ' VA'];
               
                    
                    %POTENCIA ACTIVA
                    P=S*cos(angI1*pi/180);
                    app.PotenciaActivaWGauge.Value=P;
                    res10=['La potencia activa es: ' num2str(P) ' W'];
                    
                    %POTENCIA REACTIVA
                    Q=S*sin(angI1*pi/180);
                    app.PotenciaReactivaVARGauge.Value=Q;
                    if Q<0
                        res11=['La potencia reactiva es: ' num2str(-Q) ' VAR (atraso)'];
                    elseif Q>0 
                        res11=['La potencia reactiva es: ' num2str(Q) ' VAR (adelanto)'];
                    else
                        res11=['La potencia reactiva es: ' num2str(Q) ' VAR'];
                    end
                
                    %FACTOR DE POTENCIA
                    FP=P/S;
                    res12=['El factor de potencia es: ' num2str(FP)];
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %                                       COMPENSACIÓN DEL SISTEMA                                %
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    app.Slider.Enable='on';
                    
                    RES14=['Sistema Eléctrico Compensado'];
                    
                    %IMPEDANCIA
                    value = app.TipoDropDown.Value;
                    switch value
                        case 'Circuitos RL serie'
                            if(reactancia)
                                Xreac=app.EditField_43.Value;
                                C1=-Xreac;
                                Z2=Z1;
                                Z1=complex(R1,C1); 
                                RES33=[''];
                            else
                                Xvi=app.EditField_43.Value;
                                XL=2*pi*f*Xvi*10^-3;
                                C1=1/(2*pi*f*XL*10^-6);
                                Z2=Z1;
                                Z1=complex(R1,-XL);
                                RES33=['El valor del capacitor para compensar el sistema eléctrico es de: ' num2str(round(C1,2)) ' uF'];
                            end
                        case 'Circuitos RC serie'
                            if(reactancia)
                                Xreac=app.EditField_43.Value;
                                C1=-Xreac;
                                Z2=Z1;
                                Z1=complex(R1,-C1); 
                                RES33=[''];
                            else
                                %Circuito RC
                                c=app.EditField_43.Value;
                                Xc=1/(2*pi*f*c*10^-6);
                                L=Xc/(2*pi*f);
                                XL=(2*pi*f*L);
                                Z2=Z1;
                                Z1=complex(R1,XL);
                                %Z2=complex(R1,-XL);
                                RES33=['El valor del inductor para compensar el sistema eléctrico es de: ' num2str(round(L*10^3,2)) ' mH'];
                            end
                    end
           
        
            [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
            RES17=['V1 = ' num2str(complex(realV1,imagV1))];
            RES18=['V1 = ' num2str(V1) ' |_ ' num2str(angV1) '° V'];
            
            
            RES19=['Z1 = ' num2str(Z1)];
            [angZ1,magZ1] = cart2pol(real(Z1),imag(Z1));
            angZ1=angZ1*180/pi;
            
            RES20=['Z1 = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
            
            RES21=['Z2 = ' num2str(Z2)];
            [angZ2,magZ2] = cart2pol(real(Z2),imag(Z2));
            angZ2=angZ2*180/pi;
            
            RES22=['Z2 = ' num2str(magZ2) ' |_ ' num2str(angZ2) '° ohms'];


            %Z3=Z1||Z2
            Z3=(Z1*Z2)/(Z1+Z2);
        
            
            RES23=['Z3 = ' num2str(Z3)];
            [angZ3,magZ3] = cart2pol(real(Z3),imag(Z3));
            angZ3=angZ3*180/pi;
            RES24=['Z3 = ' num2str(magZ3) ' |_ ' num2str(angZ3) '° ohms'];
            
            RES25=['La impedancia equivalente es: ' num2str(Z3)];
            RES26=['Zeq = ' num2str(magZ3) ' |_ ' num2str(angZ3) '° ohms'];
        
            %Corriente
            [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
        
            I1=complex(realV1, imagV1)/Z3;
            RES27=['I1 = ' num2str(I1)];
            [angI1,magI1] = cart2pol(real(I1),imag(I1));
            angI1=angI1*180/pi;
            RES28=['I1 = ' num2str(magI1) ' |_ ' num2str(angI1) '° A'];
        
            %POTENCIA APARENTE
            S=V1*magI1;
            RES29=['La potencia aparente es: ' num2str(S) ' VA'];
        
            %POTENCIA ACTIVA
            P=S*cos(angI1*pi/180);
            RES30=['La potencia activa es: ' num2str(P) ' W'];
        
            %POTENCIA REACTIVA
            Q=S*sin(angI1*pi/180);
            if Q<0
                RES31=['La potencia reactiva es: ' num2str(-Q) ' VAR (atraso)'];
            elseif Q>0 
                RES31=['La potencia reactiva es: ' num2str(Q) ' VAR (adelanto)'];
            else
                RES31=['La potencia reactiva es: ' num2str(Q) ' VAR'];
            end
            
                    app.PotenciaAparenteVAGauge.Value=S;
                    app.PotenciaActivaWGauge.Value=P;
                    app.PotenciaReactivaVARGauge.Value=Q;
        
            %FACTOR DE POTENCIA
            FP=P/S;
            RES32=['El factor de potencia es: ' num2str(FP)];
        
            %ListBox
            datos = {'Sistema Eléctrico No Compensado';'';mat2str(res1);''; mat2str(res2);''; mat2str(res3);''; mat2str(res4);''; mat2str(res5);''; mat2str(res6);'';
                mat2str(res7);''; mat2str(res8);''; mat2str(res9);''; mat2str(res10);''; mat2str(res11);''; mat2str(res12);'';
                mat2str(RES14);''; mat2str(RES17);''; mat2str(RES18);''; mat2str(RES19);'';
                mat2str(RES20);''; mat2str(RES21);''; mat2str(RES22);''; mat2str(RES23);''; mat2str(RES24);''; mat2str(RES25);'';
                mat2str(RES26);''; mat2str(RES27);''; mat2str(RES28);''; mat2str(RES29);''; mat2str(RES30);''; mat2str(RES31);
                ''; mat2str(RES32); ''; mat2str(RES33)};
            app.ListBox.Items = datos;
            
            %SEÑALES SENOIDALES
            w=2*pi*f;
            t=0:0.0001:(1/f)*3;
            
            Vm=V1*cos(w*t+angV1*pi/180);
            Im=magI1*cos(w*t+angI1*pi/180);
                    
                    
                    %GRÁFICOS
                    %Gráfica de comparación de voltaje vs corriente
                    yyaxis(app.UIAxes_7,'left');
                    plot(app.UIAxes_7,t,Vm,'LineWidth',2,'Color', [0.27, 0.50, 0.70 ]);
                    title(app.UIAxes_7,'Sistema Eléctrico Compensado');
                    
                    ylabel(app.UIAxes_7,'Voltaje (V)','LineWidth',2,'Color', [0.27, 0.50, 0.70 ]); 
                    
                    yyaxis(app.UIAxes_7,'right');
                    plot(app.UIAxes_7,t,Im,'LineWidth',2,'Color', [0.69, 0.13 0.13]);
                    
                    
                    xlabel(app.UIAxes_7,'Tiempo (seg)'); 
                    ylabel(app.UIAxes_7,'Corriente (A)','LineWidth',2,'Color', [0.69, 0.13 0.13]);
        
                    V=['V = ' num2str(V1) ' V'];
                    I=['I = ' num2str(round(magI1,2)) ' A'];
                    
                    legend(app.UIAxes_7,V, I);
                    app.UIAxes_7.FontName = 'Century Gothic';
                    app.UIAxes_7.FontWeight = 'bold';
                    app.UIAxes_7.Box = 'on';
                    app.UIAxes_7.XGrid = 'on';
                    app.UIAxes_7.YGrid = 'on';
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            f=app.FrecuenciaHzEditField.Value;

            V1=app.VoltajeVEditField.Value;
            angV1=app.nguloEditField.Value;
            
            R1=app.ResistenciaEditField.Value;
            
            X=app.EditField_43.Value;
            
            if (f==0||V1==0||R1==0||X==0)
                    
                    message = sprintf('Datos ingresados incorrectamente, revise que no existan campos vacíos.');
                    uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Alerta','Icon','warning');
                  
            else
                inductor=app.InductanciamHButton.Value;
                reactancia=app.ReactanciaButton.Value;
                            
                if(reactancia)
                    Xreac=app.EditField_43.Value;
                else
                    Xvi=app.EditField_43.Value;    
                end
                
                %IMPEDANCIA
                value = app.TipoDropDown.Value;
                switch value
                    case 'Circuitos RL serie'
                        if(reactancia)
                            Z1=complex(R1,Xreac);
                        else
                            %Circuito RL
                            Xvi=Xvi*10^-3;
                            XL=2*pi*f*Xvi;
                            Z1=complex(R1,XL);    
                        end
                    case 'Circuitos RC serie'
                        if(reactancia)
                            Z1=complex(R1,-Xreac);
                        else
                            %Circuito RC
                            c=Xvi*10^-6;
                            Xc=1/(2*pi*f*c);
                            Z1=complex(R1,-Xc);    
                        end
                end
        
                [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
                
                res1=['V1 = ' num2str(complex(realV1,imagV1))];
                res2=['V1 = ' num2str(V1) ' |_ ' num2str(angV1) '° V'];
            
                
                res3=['Z1 = ' num2str(Z1)];
                [angZ1,magZ1] = cart2pol(real(Z1),imag(Z1));
                angZ1=angZ1*180/pi;
                
                res4=['Z1 = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
            
                res5=['La impedancia equivalente es: ' num2str(Z1)];
                res6=['Zeq = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
                
                %CORRIENTE
                [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
            
                I1=complex(realV1, imagV1)/Z1;
                res7=['I1 = ' num2str(I1)];
                [angI1,magI1] = cart2pol(real(I1),imag(I1));
                angI1=angI1*180/pi;
                
                res8=['I1 = ' num2str(magI1) ' |_ ' num2str(angI1) '° A'];
                
            
                %POTENCIA APARENTE
                S=V1*magI1;
                app.PotenciaAparenteVAGauge.Value=S;
                res9=['La potencia aparente es: ' num2str(S) ' VA'];
           
                
                %POTENCIA ACTIVA
                P=S*cos(angI1*pi/180);
                app.PotenciaActivaWGauge.Value=P;
                res10=['La potencia activa es: ' num2str(P) ' W'];
                
                %POTENCIA REACTIVA
                Q=S*sin(angI1*pi/180);
                app.PotenciaReactivaVARGauge.Value=Q;
                if Q<0
                    res11=['La potencia reactiva es: ' num2str(-Q) ' VAR (atraso)'];
                elseif Q>0 
                    res11=['La potencia reactiva es: ' num2str(Q) ' VAR (adelanto)'];
                else
                    res11=['La potencia reactiva es: ' num2str(Q) ' VAR'];
                end
            
                %FACTOR DE POTENCIA
                FP=P/S;
                res12=['El factor de potencia es: ' num2str(FP)];
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %                                       COMPENSACIÓN DEL SISTEMA  Barra                            %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.Slider.Enable='on';
                
                RES14=['Sistema Eléctrico Compensado'];
                
                
                %IMPEDANCIA
                value = app.TipoDropDown.Value;
                switch value
                    case 'Circuitos RL serie'
                        if(reactancia)
                            Xreac=app.EditField_43.Value;
                            C1=-Xreac;
                             C1=changingValue*C1/100;
                            Z2=Z1;
                            Z1=complex(R1,C1); 
                            RES33=[''];
                        else
                            Xvi=app.EditField_43.Value;
                            XL=2*pi*f*Xvi*10^-3;
                            XL=(100*XL)/(changingValue);
                            C1=1/(2*pi*f*XL*10^-6);
                            
                            Z2=Z1;
                            Z1=complex(R1,-XL);
                            RES33=['El valor del capacitor para compensar el sistema eléctrico es de: ' num2str(round(C1,2)) ' uF'];
                        end
                    case 'Circuitos RC serie'
                        if(reactancia)
                            Xreac=app.EditField_43.Value;
                            C1=-Xreac;
                            C1=changingValue*C1/100;
                            Z2=Z1;
                            Z1=complex(R1,-C1); 
                            RES33=[''];
                        else
                            %Circuito RC
                            c=app.EditField_43.Value;
                            Xc=1/(2*pi*f*c*10^-6);
                            L=Xc/(2*pi*f);
                            L=(changingValue*L)/100;
                            XL=(2*pi*f*L);
                            Z2=Z1;
                            Z1=complex(R1,XL);
                            %Z2=complex(R1,-XL);
                            RES33=['El valor del inductor para compensar el sistema eléctrico es de: ' num2str(round(L*10^3,2)) ' mH'];
                        end
                end
       
    
        [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
        RES17=['V1 = ' num2str(complex(realV1,imagV1))];
        RES18=['V1 = ' num2str(V1) ' |_ ' num2str(angV1) '° V'];
        
        
        RES19=['Z1 = ' num2str(Z1)];
        [angZ1,magZ1] = cart2pol(real(Z1),imag(Z1));
        angZ1=angZ1*180/pi;
        
        RES20=['Z1 = ' num2str(magZ1) ' |_ ' num2str(angZ1) '° ohms'];
        
        RES21=['Z2 = ' num2str(Z2)];
        [angZ2,magZ2] = cart2pol(real(Z2),imag(Z2));
        angZ2=angZ2*180/pi;
        
        RES22=['Z2 = ' num2str(magZ2) ' |_ ' num2str(angZ2) '° ohms'];


        %Z3=Z1||Z2
        Z3=(Z1*Z2)/(Z1+Z2);
    
        
        RES23=['Z3 = ' num2str(Z3)];
        [angZ3,magZ3] = cart2pol(real(Z3),imag(Z3));
        angZ3=angZ3*180/pi;
        RES24=['Z3 = ' num2str(magZ3) ' |_ ' num2str(angZ3) '° ohms'];
        
        RES25=['La impedancia equivalente es: ' num2str(Z3)];
        RES26=['Zeq = ' num2str(magZ3) ' |_ ' num2str(angZ3) '° ohms'];
    
        %Corriente
        [realV1, imagV1]=pol2cart((angV1*pi/180),V1);
    
        I1=complex(realV1, imagV1)/Z3;
        RES27=['I1 = ' num2str(I1)];
        [angI1,magI1] = cart2pol(real(I1),imag(I1));
        angI1=angI1*180/pi;
        RES28=['I1 = ' num2str(magI1) ' |_ ' num2str(angI1) '° A'];
    
        %POTENCIA APARENTE
        S=V1*magI1;
        RES29=['La potencia aparente es: ' num2str(S) ' VA'];
    
        %POTENCIA ACTIVA
        P=S*cos(angI1*pi/180);
        RES30=['La potencia activa es: ' num2str(P) ' W'];
    
        %POTENCIA REACTIVA
        Q=S*sin(angI1*pi/180);
        if Q<0
            RES31=['La potencia reactiva es: ' num2str(-Q) ' VAR (atraso)'];
        elseif Q>0 
                RES31=['La potencia reactiva es: ' num2str(Q) ' VAR (adelanto)'];
            else
                RES31=['La potencia reactiva es: ' num2str(Q) ' VAR'];
        end
        
                app.PotenciaAparenteVAGauge.Value=S;
                app.PotenciaActivaWGauge.Value=P;
                app.PotenciaReactivaVARGauge.Value=Q;
    
        %FACTOR DE POTENCIA
        FP=P/S;
        RES32=['El factor de potencia es: ' num2str(FP)];
    
        %ListBox
        datos = {'Sistema Eléctrico No Compensado';'';mat2str(res1);''; mat2str(res2);''; mat2str(res3);''; mat2str(res4);''; mat2str(res5);''; mat2str(res6);'';
            mat2str(res7);''; mat2str(res8);''; mat2str(res9);''; mat2str(res10);''; mat2str(res11);''; mat2str(res12);'';
            mat2str(RES14);''; mat2str(RES17);''; mat2str(RES18);''; mat2str(RES19);'';
            mat2str(RES20);''; mat2str(RES21);''; mat2str(RES22);''; mat2str(RES23);''; mat2str(RES24);''; mat2str(RES25);'';
            mat2str(RES26);''; mat2str(RES27);''; mat2str(RES28);''; mat2str(RES29);''; mat2str(RES30);''; mat2str(RES31);
            ''; mat2str(RES32); ''; mat2str(RES33)};
        app.ListBox.Items = datos;
        
        %SEÑALES SENOIDALES
        w=2*pi*f;
        t=0:0.0001:(1/f)*3;
        
        Vm=V1*cos(w*t+angV1*pi/180);
        Im=magI1*cos(w*t+angI1*pi/180);
                
                
                %GRÁFICOS
                %Gráfica de comparación de voltaje vs corriente
                yyaxis(app.UIAxes_7,'left');
                plot(app.UIAxes_7,t,Vm,'LineWidth',2,'Color', [0.27, 0.50, 0.70 ]);
                title(app.UIAxes_7,'Sistema Eléctrico Compensado');
                
                ylabel(app.UIAxes_7,'Voltaje (V)','LineWidth',2,'Color', [0.27, 0.50, 0.70 ]); 
                
                yyaxis(app.UIAxes_7,'right');
                plot(app.UIAxes_7,t,Im,'LineWidth',2,'Color', [0.69, 0.13 0.13]);
                
                
                xlabel(app.UIAxes_7,'Tiempo (seg)'); 
                ylabel(app.UIAxes_7,'Corriente (A)','LineWidth',2,'Color', [0.69, 0.13 0.13]);
    
                V=['V = ' num2str(V1) ' V'];
                I=['I = ' num2str(round(magI1,2)) ' A'];
                
                legend(app.UIAxes_7,V, I);
                app.UIAxes_7.FontName = 'Century Gothic';
                app.UIAxes_7.FontWeight = 'bold';
                app.UIAxes_7.Box = 'on';
                app.UIAxes_7.XGrid = 'on';
                app.UIAxes_7.YGrid = 'on';
            end        
        end

        % Value changed function: LimpiarCamposSwitch_3
        function LimpiarCamposSwitch_3ValueChanged(app, event)
            value = app.LimpiarCamposSwitch_3.Value;
            switch value
                case 'On'
               
                    f = waitbar(0,'Espere porfavor...');
                    pause(.5)
                    

                    %Datos de placa
                    app.PotenciaAparenteEditField.Value = 0;
                    app.LadoDeAltaEditField.Value = 0;
                    app.LadoDeBajaEditField.Value = 0;
                    app.FrecuenciaEditField_3.Value = 60;
                    %Datos de pruebas
                    app.VoltajeEditField.Value = 0;
                    app.CorrienteEditField.Value =0;
                    app.PotenciaEditField.Value =0;
                    app.VoltajeEditField_2.Value = 0;
                    app.CorrienteEditField_2.Value =0;
                    app.PotenciaEditField_2.Value =0;
                    
                    waitbar(.33,f,'Limpiando campos');
                    pause(0.5)
                    
                    %PU Base
                    app.SbEditField.Value=0;
                    app.Vb1EditField.Value=0;
                    app.Vb2EditField.Value=0;
                    app.Ib1EditField.Value=0;
                    app.Ib2EditField.Value=0;
                    %Eficiencia
                    app.EditField_39.Value=0;
                    %Regulación
                    app.EditField_40.Value=0;
                    app.EditField_41.Value=0;
                    app.EditField_42.Value=0;
                    app.V1EditField.Value=0;
                    app.RVEditField.Value=0;
                    %Plena carga
                    app.PotenciaaparenteEditField.Value=0;
                    app.FactordepotenciaEditField.Value=0;
                    app.VoltajeEditField_3.Value=0;
                    app.CorrienteEditField_3.Value=0;
                    %Voc
                    app.EditField_Vca.Value=0;
                    app.EditField_5.Value=0; 
                    app.EditField_6.Value=0;
                    app.EditField_4.Value=0;
                    %Ioc
                    app.EditField_7.Value=0;
                    app.EditField_8.Value=0;
                    app.EditField_9.Value=0;
                    %Poc
                    app.EditField_10.Value=0;
                    app.EditField_11.Value=0;
                    app.EditField_12.Value=0;
                    %Vsc
                    app.EditField_13.Value=0;
                    app.EditField_14.Value=0;
                    app.EditField_15.Value=0;
                    %Isc
                    app.EditField_16.Value=0;
                    app.EditField_17.Value=0;
                    app.EditField_18.Value=0;
                    %Psc
                    app.EditField_19.Value=0;
                    app.EditField_20.Value=0;
                    app.EditField_21.Value=0;
                    
                    waitbar(.67,f,'Seguimos limpiando los campos');
                    pause(0.5)
                    
                    %Y
                    app.EditField_22.Value=0;
                    app.EditField_23.Value=0;
                    app.EditField_24.Value=0;
                    %Poc
                    app.EditField_27.Value=0;
                    app.EditField_28.Value=0;
                    app.EditField_29.Value=0;
                    app.EditField_30.Value=0;
                    %Y
                    app.EditField_38.Value=0;
                    app.EditField_39.Value=0;
                    %Ze
                    app.EditField_31.Value=0;
                    app.EditField_32.Value=0;
                    app.EditField_33.Value=0;
                    app.EditField_34.Value=0;
                    app.EditField_35.Value=0;
                    app.EditField_36.Value=0;
                    app.EditField_37.Value=0;
                    %Z
                    app.EditField_40.Value=0;
                    app.EditField_41.Value=0;
                    
                    app.RegulacindeVoltajeGauge.Value=0;
                    app.EficienciaGauge.Value=0;
                    
                    waitbar(1,f,'Finalizando');
                    pause(.5)
                    close(f)   
                    
                    app.LimpiarCamposSwitch_3.Value = 'Off';
                case 'Off'
            end
        end

        % Value changed function: PotenciaAparenteEditField
        function PotenciaAparenteEditFieldValueChanged(app, event)
            value = app.PotenciaAparenteEditField.Value;
            %PU Base
            app.SbEditField.Value=value;
            app.PotenciaaparenteEditField.Value=value;
            RTb=app.LadoDeBajaEditField.Value;
            if (RTb~=0 )
                Sb=value*1000;
                app.Ib2EditField.Value=(Sb/RTb);
                app.CorrienteEditField_3.Value=(Sb/RTb);    
            end
            
            RTa=app.LadoDeAltaEditField.Value;
            if (RTa~=0)
                Sb=value*1000;
                app.Ib1EditField.Value=(Sb/RTa);
            end
        end

        % Value changed function: LadoDeAltaEditField
        function LadoDeAltaEditFieldValueChanged(app, event)
            RTa = app.LadoDeAltaEditField.Value;
            %PU Base
            if(RTa~=0)
                Sb = app.PotenciaAparenteEditField.Value * 1000;
                app.Ib1EditField.Value=(Sb/RTa);
                app.Vb1EditField.Value=RTa;
            else
                app.Ib1EditField.Value=0;
                app.Vb1EditField.Value=0;
            end
        end

        % Value changed function: LadoDeBajaEditField
        function LadoDeBajaEditFieldValueChanged(app, event)
            RTb = app.LadoDeBajaEditField.Value;
            %PU Base
            if(RTb~=0)
                Sb = app.PotenciaAparenteEditField.Value * 1000;
                app.Ib2EditField.Value=(Sb/RTb);
                app.Vb2EditField.Value=RTb;
                app.CorrienteEditField_3.Value=(Sb/RTb);
                app.VoltajeEditField_3.Value=RTb;
            else
                app.Ib2EditField.Value=0;
                app.Vb2EditField.Value=0;
                app.CorrienteEditField_3.Value=0;
                app.VoltajeEditField_3.Value=0;
            end
        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            %Datos de placa
                Q=app.PotenciaAparenteEditField.Value;
                RTa=app.LadoDeAltaEditField.Value;
                RTb=app.LadoDeBajaEditField.Value;
                F=app.FrecuenciaEditField_3.Value;
                %Datos de pruebas
                Vca=app.VoltajeEditField.Value;
                Ica=app.CorrienteEditField.Value;
                Pca=app.PotenciaEditField.Value;
                Vcs=app.VoltajeEditField_2.Value;
                Ics=app.CorrienteEditField_2.Value;
                Pcs=app.PotenciaEditField_2.Value;
                %Plena carga
                PQ=app.PotenciaaparenteEditField.Value;
                FP=app.FactordepotenciaEditField.Value;
                PV=app.VoltajeEditField_3.Value;
                PI=app.CorrienteEditField_3.Value;
                if (Q==0||RTa==0||RTb==0||Vca==0||Ica==0||Pca==0||Vcs==0||Ics==0||Pcs==0||PQ==0||FP==0||PV==0||PI==0)
                    
                    message = sprintf('Datos ingresados incorrectamente, revise que no existan campos vacíos.');
                    uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Alerta','Icon','warning');
                  
                else
                    %PU Base
                    Sb=1000*app.SbEditField.Value;
                    Vb1=app.Vb1EditField.Value;
                    Vb2=app.Vb2EditField.Value;
                    Ib1=app.Ib1EditField.Value;
                    Ib2=app.Ib2EditField.Value;
                    %Voc
                    app.EditField_Vca.Value=Vca;
                    app.EditField_4.Value=Vb2; 
                    Voc=Vca/Vb2;
                    app.EditField_5.Value=Voc;
                    %Ioc
                    app.EditField_6.Value=Ica;
                    app.EditField_7.Value=Ib2;
                    Ioc=Ica/Ib2;
                    app.EditField_8.Value=Ioc;
                    %Poc
                    app.EditField_9.Value=Pca;
                    app.EditField_10.Value=Sb;
                    Poc=Pca/Sb;
                    app.EditField_11.Value=Poc;
                    %Vsc
                    app.EditField_12.Value=Vcs;
                    app.EditField_13.Value=Vb1;
                    Vsc=Vcs/Vb1;
                    app.EditField_14.Value=Vsc;
                    %Isc
                    app.EditField_15.Value=Ics;
                    app.EditField_16.Value=Ib1;
                    Isc=Ics/Ib1;
                    app.EditField_17.Value=Isc;
                    %Psc
                    app.EditField_18.Value=Pcs;
                    app.EditField_19.Value=Sb;
                    Psc=Pcs/Sb;
                    app.EditField_20.Value=Psc;
                    
                    %Y
                    app.EditField_21.Value=Ioc;
                    app.EditField_22.Value=Voc;
                    Ymag=Ioc/Voc;
                    app.EditField_23.Value=Ymag;
                    %Poc
                    app.EditField_25.Value=Poc;
                    app.EditField_26.Value=Voc;
                    app.EditField_27.Value=Ioc;
                    tetha=-acosd(Poc/(Voc*Ioc));
                    app.EditField_24.Value=tetha;
                    %Y
                    app.EditField_28.Value=Ymag;
                    app.EditField_29.Value=tetha;
                    %Ze
                    app.EditField_30.Value=Vsc;
                    app.EditField_31.Value=Isc;
                    Ze=Vsc/Isc;
                    app.EditField_32.Value=Ze;
                    
                    app.EditField_36.Value=Isc;
                    app.EditField_34.Value=Psc;
                    app.EditField_35.Value=Vsc;
                    ZeTetha=acosd(Psc/(Vsc*Isc));
                    app.EditField_33.Value=ZeTetha;
                    %Z
                    app.EditField_37.Value=Ze;
                    app.EditField_38.Value=ZeTetha;


                    PU=PQ*1/app.SbEditField.Value;
                    PUV=PV*1/Vca;
                    PUIb2=PI*1/Ib2;
                    
                    Iang=acosd(FP);
                    [Ireal,Iimag]=pol2cart(-Iang,PUIb2);
                    I2=complex(Ireal,Iimag);
                    
                    app.EditField_40.Value=PUIb2;
                    app.EditField_41.Value=-Iang;
                    
                    [Zreal,Zimag]=pol2cart(ZeTetha,Ze);
                    ZE=complex(Zreal,Zimag);
                    
%                     [PUVreal,PUVimag]=pol2cart(0,PUV);
%                     puv=complex(PUVreal,PUVimag);
                    
                    V1=(I2*ZE)+1;
                    
                    [V1ang,V1mag] = cart2pol(imag(V1),real(V1));
                    app.V1EditField.Value=V1mag;
                    app.EditField_42.Value=V1ang;
                    
                    R=((V1mag-PUV)/PUV)*100;
                    app.RVEditField.Value=R;
                    
                    n=((FP*PU)/((FP*PU)+(Psc+Poc)))*100;
                    
                    app.EditField_39.Value=n;
                    
                    app.RegulacindeVoltajeGauge.Value=R;
                    app.EficienciaGauge.Value=n;
                
                    
                end
        end

        % Menu selected function: DedicatoriaMenu
        function DedicatoriaMenuSelected(app, event)
            message = sprintf('Dedico esta aplicación con mucho cariño, especialmente a Dios que me ha dado la oportunidad y el deseo de poder hacer cada vez mis sueños una realidad, incluyo a personas que han sido la razón de mi existir y a la vez un pilar fundamental en mi desarrollo como profesionista, agradezco a mi familia, y no menos importante a mi gran amigo y hermano "Diego Ulises Martínez Aguilar".');
            uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Dedicatoria','Icon','success'); 
        end

        % Menu selected function: ResumenMenu
        function ResumenMenuSelected(app, event)
            message = sprintf('Se desarrolló un software para el cálculo de la eficiencia y regulación de voltaje para un transformador reductor, compensación de circuitos RL, RC, y medidor de armónicos. Nos permite analizar de manera rápida e intuitiva los parámetros y las gráficas de un Sistema Eléctrico De Potencia AC, a través del diseñador de aplicaciones de Matlab, App Designer.\n\nLa aplicación desarrollada consta de una interfaz gráfica de usuario, código que realiza las acciones subyacentes y datos asociados de entrada al sistema. La idea del proyecto es empaquetar en un único archivo para distribuirse a otras personas, en tal caso a alumnos de la carrera de Ingeniería Eléctrica del Tecnológico Nacional de México.');
            uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Resumen','Icon','info');
       
        end

        % Menu selected function: AcercadeMenu
        function AcercadeMenuSelected(app, event)
            message = sprintf('Desarrollado por Diego Villegas Govea en enero del 2019, como Proyecto de Residencias Profesionales. \n\nEstudiante del Tecnológico Nacional de México campus Morelia, de la carrera de  Ingeniería Eléctrica con especialidad en Sistemas Eléctricos de Potencia.');
            uialert(app.SistemasElctricosdePotenciaUIFigure,message,'Acerca de','Icon','success');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SistemasElctricosdePotenciaUIFigure = uifigure;
            app.SistemasElctricosdePotenciaUIFigure.Name = 'Sistemas Eléctricos de Potencia';
            app.SistemasElctricosdePotenciaUIFigure.CloseRequestFcn = createCallbackFcn(app, @SistemasElctricosdePotenciaUIFigureCloseRequest, true);
            app.InicioMenu = uimenu(app.SistemasElctricosdePotenciaUIFigure);
            app.InicioMenu.Text = 'Inicio';
            app.ResumenMenu = uimenu(app.InicioMenu);
            app.ResumenMenu.MenuSelectedFcn = createCallbackFcn(app, @ResumenMenuSelected, true);
            app.ResumenMenu.Accelerator = 'R';
            app.ResumenMenu.Text = 'Resumen';
            app.AcercadeMenu = uimenu(app.InicioMenu);
            app.AcercadeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeMenuSelected, true);
            app.AcercadeMenu.Accelerator = 'A';
            app.AcercadeMenu.Text = 'Acerca de';
            app.DedicatoriaMenu = uimenu(app.InicioMenu);
            app.DedicatoriaMenu.MenuSelectedFcn = createCallbackFcn(app, @DedicatoriaMenuSelected, true);
            app.DedicatoriaMenu.Accelerator = 'D';
            app.DedicatoriaMenu.Text = 'Dedicatoria';
            app.TabGroup = uitabgroup(app.SistemasElctricosdePotenciaUIFigure);
            app.InformacindelSistemaTab = uitab(app.TabGroup);
            app.InformacindelSistemaTab.Title = 'Información del Sistema';
            app.UIAxes = uiaxes(app.InformacindelSistemaTab);
            app.UIAxes_2 = uiaxes(app.InformacindelSistemaTab);
            app.UIAxes_3 = uiaxes(app.InformacindelSistemaTab);
            app.UIAxes_4 = uiaxes(app.InformacindelSistemaTab);
            app.UIAxes_5 = uiaxes(app.InformacindelSistemaTab);
            app.UIAxes_6 = uiaxes(app.InformacindelSistemaTab);
            app.DIVISINDEESTUDIOSPROFESIONALESLabel = uilabel(app.InformacindelSistemaTab);
            app.DIVISINDEESTUDIOSPROFESIONALESLabel.Text = 'DIVISIÓN DE ESTUDIOS PROFESIONALES';
            app.INSTITUTOTECNOLGICODEMORELIALabel = uilabel(app.InformacindelSistemaTab);
            app.INSTITUTOTECNOLGICODEMORELIALabel.Text = 'INSTITUTO TECNOLÓGICO DE MORELIA';
            app.APPDESIGNERLabel = uilabel(app.InformacindelSistemaTab);
            app.APPDESIGNERLabel.Text = 'APP DESIGNER';
            app.MATLABR2017bTheMathWorksIncLabel = uilabel(app.InformacindelSistemaTab);
            app.MATLABR2017bTheMathWorksIncLabel.Text = 'MATLAB R2017b, The MathWorks, Inc.';
            app.DIEGOULISESMARTNEZAGUILARLabel = uilabel(app.InformacindelSistemaTab);
            app.DIEGOULISESMARTNEZAGUILARLabel.Text = 'DIEGO ULISES MARTÍNEZ AGUILAR';
            app.NdeControl15121185Label = uilabel(app.InformacindelSistemaTab);
            app.NdeControl15121185Label.Text = 'N° de Control 15121185';
            app.DEPARTAMENTODEINGENIERAELCTRICALabel = uilabel(app.InformacindelSistemaTab);
            app.DEPARTAMENTODEINGENIERAELCTRICALabel.Text = 'DEPARTAMENTO DE INGENIERÍA ELÉCTRICA';
            app.MORELIAMICHOACNMXICOLabel = uilabel(app.InformacindelSistemaTab);
            app.MORELIAMICHOACNMXICOLabel.Text = 'MORELIA, MICHOACÁN, MÉXICO';
            app.DRJOSLUISMONROYMORALESLabel = uilabel(app.InformacindelSistemaTab);
            app.DRJOSLUISMONROYMORALESLabel.Text = 'DR. JOSÉ LUIS MONROY MORALES';
            app.ASESORLabel = uilabel(app.InformacindelSistemaTab);
            app.ASESORLabel.Text = 'ASESOR';
            app.ENERO2019Label = uilabel(app.InformacindelSistemaTab);
            app.ENERO2019Label.Text = 'ENERO 2019';
            app.MedidordeArmnicos_2 = uilabel(app.InformacindelSistemaTab);
            app.MedidordeArmnicos_2.Text = 'Medidor de Armónicos';
            app.ClculoyCompensacindeunSistemaElctricodeACLabel_2 = uilabel(app.InformacindelSistemaTab);
            app.ClculoyCompensacindeunSistemaElctricodeACLabel_2.Text = 'Cálculo y Compensación de un Sistema Eléctrico de AC';
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_2 = uilabel(app.InformacindelSistemaTab);
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_2.Text = 'Cálculo de Eficiencia y Regulación de Voltaje de un Transformador Reductor';
            app.MedidordeArmnicos_3 = uilabel(app.InformacindelSistemaTab);
            app.MedidordeArmnicos_3.Text = '';
            app.Label_4 = uilabel(app.InformacindelSistemaTab);
            app.Label_4.Text = '';
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_3 = uilabel(app.InformacindelSistemaTab);
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_3.Text = '';
            app.ButtonArmonicos_2 = uibutton(app.InformacindelSistemaTab, 'push');
            app.ButtonArmonicos_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonArmonicos_2Pushed, true);
            app.ButtonArmonicos_2.Icon = 'Logo Armonicos.jpeg';
            app.ButtonArmonicos_2.Text = '';
            app.ButtonCompensacion_2 = uibutton(app.InformacindelSistemaTab, 'push');
            app.ButtonCompensacion_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonCompensacion_2Pushed, true);
            app.ButtonCompensacion_2.Icon = 'Logo Compensacion.jpeg';
            app.ButtonCompensacion_2.Text = '';
            app.ButtonTransformador_2 = uibutton(app.InformacindelSistemaTab, 'push');
            app.ButtonTransformador_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonTransformador_2Pushed, true);
            app.ButtonTransformador_2.Icon = 'Logo Transformadores.jpeg';
            app.ButtonTransformador_2.Text = '';
            app.AppTransformadoresLabel = uilabel(app.InformacindelSistemaTab);
            app.AppTransformadoresLabel.Text = 'App. Transformadores';
            app.AppCompensacinLabel = uilabel(app.InformacindelSistemaTab);
            app.AppCompensacinLabel.Text = 'App. Compensación';
            app.AppArmnicosLabel = uilabel(app.InformacindelSistemaTab);
            app.AppArmnicosLabel.Text = 'App. Armónicos';
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_4 = uilabel(app.InformacindelSistemaTab);
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_4.Text = '';
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_5 = uilabel(app.InformacindelSistemaTab);
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel_5.Text = '';
            app.AppTransformadoresTab = uitab(app.TabGroup);
            app.AppTransformadoresTab.Title = 'App. Transformadores';
            app.imgTransformador = uibutton(app.AppTransformadoresTab, 'push');
            app.imgTransformador.Icon = 'TEC 1.jpg';
            app.imgTransformador.Text = '';
            app.ButtonTransformador = uibutton(app.AppTransformadoresTab, 'push');
            app.ButtonTransformador.ButtonPushedFcn = createCallbackFcn(app, @ButtonTransformadorPushed, true);
            app.ButtonTransformador.Icon = 'Logo Transformadores.jpeg';
            app.ButtonTransformador.Text = '';
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel = uilabel(app.AppTransformadoresTab);
            app.ClculodeEficienciayRegulacindeunTransformadorReductorLabel.Text = 'Cálculo de Eficiencia y Regulación de Voltaje de un Transformador Reductor';
            app.Label_2 = uilabel(app.AppTransformadoresTab);
            app.Label_2.Text = '';
            app.PanelTransformadores = uipanel(app.AppTransformadoresTab);
            app.DATOSDEPRUEBASPanel = uipanel(app.PanelTransformadores);
            app.DATOSDEPRUEBASPanel.Title = 'DATOS DE PRUEBAS';
            app.LadodebajaCALabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.LadodebajaCALabel.Text = 'Lado de baja (CA):';
            app.LadodealtaCSLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.LadodealtaCSLabel.Text = 'Lado de alta (CS):';
            app.VoltajeEditFieldLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.VoltajeEditFieldLabel.Text = 'Voltaje';
            app.VoltajeEditField = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.CorrienteEditFieldLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.CorrienteEditFieldLabel.Text = 'Corriente';
            app.CorrienteEditField = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.PotenciaEditFieldLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.PotenciaEditFieldLabel.Text = 'Potencia';
            app.PotenciaEditField = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.CorrienteEditField_2Label = uilabel(app.DATOSDEPRUEBASPanel);
            app.CorrienteEditField_2Label.Text = 'Corriente';
            app.CorrienteEditField_2 = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.VoltajeEditField_2Label = uilabel(app.DATOSDEPRUEBASPanel);
            app.VoltajeEditField_2Label.Text = 'Voltaje';
            app.VoltajeEditField_2 = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.PotenciaEditField_2Label = uilabel(app.DATOSDEPRUEBASPanel);
            app.PotenciaEditField_2Label.Text = 'Potencia';
            app.PotenciaEditField_2 = uieditfield(app.DATOSDEPRUEBASPanel, 'numeric');
            app.VLabel_14 = uilabel(app.DATOSDEPRUEBASPanel);
            app.VLabel_14.Text = 'V';
            app.ALabel_3 = uilabel(app.DATOSDEPRUEBASPanel);
            app.ALabel_3.Text = 'A';
            app.WLabel = uilabel(app.DATOSDEPRUEBASPanel);
            app.WLabel.Text = 'W';
            app.VLabel_15 = uilabel(app.DATOSDEPRUEBASPanel);
            app.VLabel_15.Text = 'V';
            app.ALabel_4 = uilabel(app.DATOSDEPRUEBASPanel);
            app.ALabel_4.Text = 'A';
            app.WLabel_2 = uilabel(app.DATOSDEPRUEBASPanel);
            app.WLabel_2.Text = 'W';
            app.DATOSDEPLACAPanel = uipanel(app.PanelTransformadores);
            app.DATOSDEPLACAPanel.Title = 'DATOS DE PLACA';
            app.RelacindetransformacinLabel = uilabel(app.DATOSDEPLACAPanel);
            app.RelacindetransformacinLabel.Text = 'Relación de transformación:';
            app.EditFieldLabel = uilabel(app.DATOSDEPLACAPanel);
            app.EditFieldLabel.Text = '/';
            app.LadoDeBajaEditField = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.LadoDeBajaEditField.ValueChangedFcn = createCallbackFcn(app, @LadoDeBajaEditFieldValueChanged, true);
            app.PotenciaaparenteEditField_2Label = uilabel(app.DATOSDEPLACAPanel);
            app.PotenciaaparenteEditField_2Label.Text = 'Potencia aparente';
            app.PotenciaAparenteEditField = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.PotenciaAparenteEditField.ValueChangedFcn = createCallbackFcn(app, @PotenciaAparenteEditFieldValueChanged, true);
            app.EditField_3Label = uilabel(app.DATOSDEPLACAPanel);
            app.EditField_3Label.Text = '';
            app.LadoDeAltaEditField = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.LadoDeAltaEditField.ValueChangedFcn = createCallbackFcn(app, @LadoDeAltaEditFieldValueChanged, true);
            app.FrecuenciaEditField_3Label = uilabel(app.DATOSDEPLACAPanel);
            app.FrecuenciaEditField_3Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_3 = uieditfield(app.DATOSDEPLACAPanel, 'numeric');
            app.FrecuenciaEditField_3.Editable = 'off';
            app.FrecuenciaEditField_3.Value = 60;
            app.KVALabel_2 = uilabel(app.DATOSDEPLACAPanel);
            app.KVALabel_2.Text = 'KVA';
            app.HzLabel_3 = uilabel(app.DATOSDEPLACAPanel);
            app.HzLabel_3.Text = 'Hz';
            app.VLabel_13 = uilabel(app.DATOSDEPLACAPanel);
            app.VLabel_13.Text = 'V';
            app.CALCULOSPanel = uipanel(app.PanelTransformadores);
            app.CALCULOSPanel.Title = 'CÁLCULOS';
            app.TabGroup_2 = uitabgroup(app.CALCULOSPanel);
            app.VocTab = uitab(app.TabGroup_2);
            app.VocTab.Title = 'Voc';
            app.VocLabel = uilabel(app.VocTab);
            app.VocLabel.Text = 'Voc = ';
            app.Vb2Label = uilabel(app.VocTab);
            app.Vb2Label.Text = 'Vb2';
            app.VcaLabel = uilabel(app.VocTab);
            app.VcaLabel.Text = 'Vca';
            app.Label_16 = uilabel(app.VocTab);
            app.Label_16.Text = '-----';
            app.VLabel_16 = uilabel(app.VocTab);
            app.VLabel_16.Text = 'V';
            app.VLabel_17 = uilabel(app.VocTab);
            app.VLabel_17.Text = 'V';
            app.Label_18 = uilabel(app.VocTab);
            app.Label_18.Text = '= ------------------------';
            app.EditField_Vca = uieditfield(app.VocTab, 'numeric');
            app.EditField_Vca.Editable = 'off';
            app.EditField_4 = uieditfield(app.VocTab, 'numeric');
            app.EditField_4.Editable = 'off';
            app.VocLabel_2 = uilabel(app.VocTab);
            app.VocLabel_2.Text = 'Voc = ';
            app.EditField_5 = uieditfield(app.VocTab, 'numeric');
            app.EditField_5.Editable = 'off';
            app.puLabel = uilabel(app.VocTab);
            app.puLabel.Text = 'pu';
            app.IocTab = uitab(app.TabGroup_2);
            app.IocTab.Title = 'Ioc';
            app.IocLabel = uilabel(app.IocTab);
            app.IocLabel.Text = 'Ioc = ';
            app.Ib2Label = uilabel(app.IocTab);
            app.Ib2Label.Text = 'Ib2';
            app.IcaLabel = uilabel(app.IocTab);
            app.IcaLabel.Text = 'Ica';
            app.Label_19 = uilabel(app.IocTab);
            app.Label_19.Text = '-----';
            app.ALabel_5 = uilabel(app.IocTab);
            app.ALabel_5.Text = 'A';
            app.ALabel_6 = uilabel(app.IocTab);
            app.ALabel_6.Text = 'A';
            app.Label_20 = uilabel(app.IocTab);
            app.Label_20.Text = '= ------------------------';
            app.EditField_6 = uieditfield(app.IocTab, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.IocTab, 'numeric');
            app.EditField_7.Editable = 'off';
            app.IocLabel_2 = uilabel(app.IocTab);
            app.IocLabel_2.Text = 'Ioc = ';
            app.EditField_8 = uieditfield(app.IocTab, 'numeric');
            app.EditField_8.Editable = 'off';
            app.puLabel_2 = uilabel(app.IocTab);
            app.puLabel_2.Text = 'pu';
            app.PocTab = uitab(app.TabGroup_2);
            app.PocTab.Title = 'Poc';
            app.PocLabel = uilabel(app.PocTab);
            app.PocLabel.Text = 'Poc = ';
            app.SbLabel = uilabel(app.PocTab);
            app.SbLabel.Text = 'Sb';
            app.PcaLabel = uilabel(app.PocTab);
            app.PcaLabel.Text = 'Pca';
            app.Label_21 = uilabel(app.PocTab);
            app.Label_21.Text = '-----';
            app.WLabel_3 = uilabel(app.PocTab);
            app.WLabel_3.Text = 'W';
            app.WLabel_4 = uilabel(app.PocTab);
            app.WLabel_4.Text = 'W';
            app.Label_22 = uilabel(app.PocTab);
            app.Label_22.Text = '= ------------------------';
            app.EditField_9 = uieditfield(app.PocTab, 'numeric');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.PocTab, 'numeric');
            app.EditField_10.Editable = 'off';
            app.PocLabel_2 = uilabel(app.PocTab);
            app.PocLabel_2.Text = 'Poc = ';
            app.EditField_11 = uieditfield(app.PocTab, 'numeric');
            app.EditField_11.Editable = 'off';
            app.puLabel_3 = uilabel(app.PocTab);
            app.puLabel_3.Text = 'pu';
            app.PerdidasenelncleoLabel = uilabel(app.PocTab);
            app.PerdidasenelncleoLabel.Text = 'Perdidas en el núcleo';
            app.VscTab = uitab(app.TabGroup_2);
            app.VscTab.Title = 'Vsc';
            app.VscLabel = uilabel(app.VscTab);
            app.VscLabel.Text = 'Vsc = ';
            app.Vb1Label = uilabel(app.VscTab);
            app.Vb1Label.Text = 'Vb1';
            app.VcsLabel = uilabel(app.VscTab);
            app.VcsLabel.Text = 'Vcs';
            app.Label_23 = uilabel(app.VscTab);
            app.Label_23.Text = '-----';
            app.VLabel_18 = uilabel(app.VscTab);
            app.VLabel_18.Text = 'V';
            app.VLabel_19 = uilabel(app.VscTab);
            app.VLabel_19.Text = 'V';
            app.Label_24 = uilabel(app.VscTab);
            app.Label_24.Text = '= ------------------------';
            app.EditField_12 = uieditfield(app.VscTab, 'numeric');
            app.EditField_12.Editable = 'off';
            app.EditField_13 = uieditfield(app.VscTab, 'numeric');
            app.EditField_13.Editable = 'off';
            app.VscLabel_2 = uilabel(app.VscTab);
            app.VscLabel_2.Text = 'Vsc = ';
            app.EditField_14 = uieditfield(app.VscTab, 'numeric');
            app.EditField_14.Editable = 'off';
            app.puLabel_4 = uilabel(app.VscTab);
            app.puLabel_4.Text = 'pu';
            app.IscTab = uitab(app.TabGroup_2);
            app.IscTab.Title = 'Isc';
            app.IscLabel = uilabel(app.IscTab);
            app.IscLabel.Text = 'Isc = ';
            app.Ib1Label = uilabel(app.IscTab);
            app.Ib1Label.Text = 'Ib1';
            app.IcsLabel = uilabel(app.IscTab);
            app.IcsLabel.Text = 'Ics';
            app.Label_25 = uilabel(app.IscTab);
            app.Label_25.Text = '-----';
            app.ALabel_7 = uilabel(app.IscTab);
            app.ALabel_7.Text = 'A';
            app.ALabel_8 = uilabel(app.IscTab);
            app.ALabel_8.Text = 'A';
            app.Label_26 = uilabel(app.IscTab);
            app.Label_26.Text = '= ------------------------';
            app.EditField_15 = uieditfield(app.IscTab, 'numeric');
            app.EditField_15.Editable = 'off';
            app.EditField_16 = uieditfield(app.IscTab, 'numeric');
            app.EditField_16.Editable = 'off';
            app.IscLabel_2 = uilabel(app.IscTab);
            app.IscLabel_2.Text = 'Isc = ';
            app.EditField_17 = uieditfield(app.IscTab, 'numeric');
            app.EditField_17.Editable = 'off';
            app.puLabel_5 = uilabel(app.IscTab);
            app.puLabel_5.Text = 'pu';
            app.PscTab = uitab(app.TabGroup_2);
            app.PscTab.Title = 'Psc';
            app.PscLabel = uilabel(app.PscTab);
            app.PscLabel.Text = 'Psc =';
            app.SbLabel_2 = uilabel(app.PscTab);
            app.SbLabel_2.Text = 'Sb';
            app.PcsLabel = uilabel(app.PscTab);
            app.PcsLabel.Text = 'Pcs';
            app.Label_27 = uilabel(app.PscTab);
            app.Label_27.Text = '-----';
            app.WLabel_5 = uilabel(app.PscTab);
            app.WLabel_5.Text = 'W';
            app.WLabel_6 = uilabel(app.PscTab);
            app.WLabel_6.Text = 'W';
            app.Label_28 = uilabel(app.PscTab);
            app.Label_28.Text = '= ------------------------';
            app.EditField_18 = uieditfield(app.PscTab, 'numeric');
            app.EditField_18.Editable = 'off';
            app.EditField_19 = uieditfield(app.PscTab, 'numeric');
            app.EditField_19.Editable = 'off';
            app.PscLabel_2 = uilabel(app.PscTab);
            app.PscLabel_2.Text = 'Psc = ';
            app.EditField_20 = uieditfield(app.PscTab, 'numeric');
            app.EditField_20.Editable = 'off';
            app.puLabel_6 = uilabel(app.PscTab);
            app.puLabel_6.Text = 'pu';
            app.PerdidasdelconductorLabel = uilabel(app.PscTab);
            app.PerdidasdelconductorLabel.Text = 'Perdidas del conductor';
            app.YTab = uitab(app.TabGroup_2);
            app.YTab.Title = 'Yθ';
            app.YLabel = uilabel(app.YTab);
            app.YLabel.Text = 'Yθ = ';
            app.VocLabel_3 = uilabel(app.YTab);
            app.VocLabel_3.Text = 'Voc';
            app.IocLabel_3 = uilabel(app.YTab);
            app.IocLabel_3.Text = 'Ioc';
            app.Label_29 = uilabel(app.YTab);
            app.Label_29.Text = '-----';
            app.Label_30 = uilabel(app.YTab);
            app.Label_30.Text = '';
            app.Label_31 = uilabel(app.YTab);
            app.Label_31.Text = '';
            app.Label_32 = uilabel(app.YTab);
            app.Label_32.Text = '= ------------------------';
            app.EditField_21 = uieditfield(app.YTab, 'numeric');
            app.EditField_21.Editable = 'off';
            app.EditField_22 = uieditfield(app.YTab, 'numeric');
            app.EditField_22.Editable = 'off';
            app.YLabel_2 = uilabel(app.YTab);
            app.YLabel_2.Text = 'Yθ = ';
            app.EditField_23 = uieditfield(app.YTab, 'numeric');
            app.EditField_23.Editable = 'off';
            app.puLabel_7 = uilabel(app.YTab);
            app.puLabel_7.Text = 'pu';
            app.PocTab_2 = uitab(app.TabGroup_2);
            app.PocTab_2.Title = 'Poc θ';
            app.PocVocIoccosLabel = uilabel(app.PocTab_2);
            app.PocVocIoccosLabel.Text = 'Poc = Voc * Ioc * cos(θ)';
            app.PocLabel_3 = uilabel(app.PocTab_2);
            app.PocLabel_3.Text = 'Poc';
            app.VocIocLabel = uilabel(app.PocTab_2);
            app.VocIocLabel.Text = 'Voc * Ioc';
            app.cos1Label = uilabel(app.PocTab_2);
            app.cos1Label.Text = 'θ = -cos^-1(-------------)';
            app.Label_33 = uilabel(app.PocTab_2);
            app.Label_33.Text = 'θ = ';
            app.EditField_24 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_24.Editable = 'off';
            app.Label_34 = uilabel(app.PocTab_2);
            app.Label_34.Text = '°';
            app.cos1Label_2 = uilabel(app.PocTab_2);
            app.cos1Label_2.Text = 'θ = -cos^-1(--------------)';
            app.EditField_25 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_25.Editable = 'off';
            app.EditField_26 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_26.Editable = 'off';
            app.Label_35 = uilabel(app.PocTab_2);
            app.Label_35.Text = '*';
            app.EditField_27 = uieditfield(app.PocTab_2, 'numeric');
            app.EditField_27.Editable = 'off';
            app.YTab_2 = uitab(app.TabGroup_2);
            app.YTab_2.Title = 'Y';
            app.YLabel_3 = uilabel(app.YTab_2);
            app.YLabel_3.Text = 'Y = ';
            app.EditField_28 = uieditfield(app.YTab_2, 'numeric');
            app.EditField_28.Editable = 'off';
            app.LLabel = uilabel(app.YTab_2);
            app.LLabel.Text = 'L';
            app.EditField_29 = uieditfield(app.YTab_2, 'numeric');
            app.EditField_29.Editable = 'off';
            app.Label_36 = uilabel(app.YTab_2);
            app.Label_36.Text = '°';
            app.ZeTab = uitab(app.TabGroup_2);
            app.ZeTab.Title = 'Ze';
            app.ZeLabel = uilabel(app.ZeTab);
            app.ZeLabel.Text = 'Ze = ';
            app.IscLabel_3 = uilabel(app.ZeTab);
            app.IscLabel_3.Text = 'Isc';
            app.VscLabel_3 = uilabel(app.ZeTab);
            app.VscLabel_3.Text = 'Vsc';
            app.Label_37 = uilabel(app.ZeTab);
            app.Label_37.Text = '-----';
            app.Label_38 = uilabel(app.ZeTab);
            app.Label_38.Text = '= -----------------------';
            app.EditField_30 = uieditfield(app.ZeTab, 'numeric');
            app.EditField_30.Editable = 'off';
            app.EditField_31 = uieditfield(app.ZeTab, 'numeric');
            app.EditField_31.Editable = 'off';
            app.ZeLabel_2 = uilabel(app.ZeTab);
            app.ZeLabel_2.Text = 'Ze = ';
            app.EditField_32 = uieditfield(app.ZeTab, 'numeric');
            app.EditField_32.Editable = 'off';
            app.puLabel_8 = uilabel(app.ZeTab);
            app.puLabel_8.Text = 'pu';
            app.ZeTab_2 = uitab(app.TabGroup_2);
            app.ZeTab_2.Title = 'Ze θ';
            app.PscVscIsccosLabel = uilabel(app.ZeTab_2);
            app.PscVscIsccosLabel.Text = 'Psc = Vsc * Isc * cos(θ)';
            app.PscLabel_3 = uilabel(app.ZeTab_2);
            app.PscLabel_3.Text = 'Psc';
            app.VscIscLabel = uilabel(app.ZeTab_2);
            app.VscIscLabel.Text = 'Vsc * Isc';
            app.cos1Label_3 = uilabel(app.ZeTab_2);
            app.cos1Label_3.Text = 'θ = cos^-1(-------------)';
            app.Label_39 = uilabel(app.ZeTab_2);
            app.Label_39.Text = 'θ = ';
            app.EditField_33 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_33.Editable = 'off';
            app.Label_40 = uilabel(app.ZeTab_2);
            app.Label_40.Text = '°';
            app.cos1Label_4 = uilabel(app.ZeTab_2);
            app.cos1Label_4.Text = 'θ = cos^-1(---------------)';
            app.EditField_34 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_34.Editable = 'off';
            app.EditField_35 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_35.Editable = 'off';
            app.Label_41 = uilabel(app.ZeTab_2);
            app.Label_41.Text = '*';
            app.EditField_36 = uieditfield(app.ZeTab_2, 'numeric');
            app.EditField_36.Editable = 'off';
            app.ZTab = uitab(app.TabGroup_2);
            app.ZTab.Title = 'Z';
            app.ZLabel = uilabel(app.ZTab);
            app.ZLabel.Text = 'Z = ';
            app.EditField_37 = uieditfield(app.ZTab, 'numeric');
            app.EditField_37.Editable = 'off';
            app.LLabel_2 = uilabel(app.ZTab);
            app.LLabel_2.Text = 'L';
            app.EditField_38 = uieditfield(app.ZTab, 'numeric');
            app.EditField_38.Editable = 'off';
            app.Label_42 = uilabel(app.ZTab);
            app.Label_42.Text = '°';
            app.CONTROLESButtonGroup_3 = uibuttongroup(app.PanelTransformadores);
            app.CONTROLESButtonGroup_3.Title = 'CONTROLES';
            app.LimpiarCamposSwitch_3Label = uilabel(app.CONTROLESButtonGroup_3);
            app.LimpiarCamposSwitch_3Label.Text = 'Limpiar Campos';
            app.LimpiarCamposSwitch_3 = uiswitch(app.CONTROLESButtonGroup_3, 'rocker');
            app.LimpiarCamposSwitch_3.ValueChangedFcn = createCallbackFcn(app, @LimpiarCamposSwitch_3ValueChanged, true);
            app.CalcularButton_3 = uibutton(app.CONTROLESButtonGroup_3, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.EFICIENCIAPanel = uipanel(app.PanelTransformadores);
            app.EFICIENCIAPanel.Title = 'EFICIENCIA';
            app.Label_45 = uilabel(app.EFICIENCIAPanel);
            app.Label_45.Text = '';
            app.Label_46 = uilabel(app.EFICIENCIAPanel);
            app.Label_46.Text = '%';
            app.EditField_39Label = uilabel(app.EFICIENCIAPanel);
            app.EditField_39Label.Text = 'η =';
            app.EditField_39 = uieditfield(app.EFICIENCIAPanel, 'numeric');
            app.EditField_39.Editable = 'off';
            app.SFPLabel = uilabel(app.EFICIENCIAPanel);
            app.SFPLabel.Text = 'S * FP';
            app.SFPPcobrePncleoLabel = uilabel(app.EFICIENCIAPanel);
            app.SFPPcobrePncleoLabel.Text = '(S*FP)+(Pcobre+Pnúcleo)';
            app.Label_47 = uilabel(app.EFICIENCIAPanel);
            app.Label_47.Text = 'η = --------------------- *100 ';
            app.Label_48 = uilabel(app.EFICIENCIAPanel);
            app.Label_48.Text = ')';
            app.Label_49 = uilabel(app.EFICIENCIAPanel);
            app.Label_49.Text = '(';
            app.REGULACINDEVOLTAJEPanel = uipanel(app.PanelTransformadores);
            app.REGULACINDEVOLTAJEPanel.Title = 'REGULACIÓN DE VOLTAJE';
            app.V1I2ZV2Label = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.V1I2ZV2Label.Text = 'V1 = I2 * Z + V2';
            app.I2Label = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.I2Label.Text = 'I2 = ';
            app.EditField_40 = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.EditField_40.Editable = 'off';
            app.LLabel_3 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.LLabel_3.Text = 'L';
            app.EditField_41 = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.EditField_41.Editable = 'off';
            app.TomandoV2Label = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.TomandoV2Label.Text = 'Tomando V2';
            app.V1EditFieldLabel = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.V1EditFieldLabel.Text = 'V1 =';
            app.V1EditField = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.V1EditField.Editable = 'off';
            app.RVEditFieldLabel = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.RVEditFieldLabel.Text = 'RV =';
            app.RVEditField = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.RVEditField.Editable = 'off';
            app.Label_50 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.Label_50.Text = '%';
            app.VLabel_21 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.VLabel_21.Text = '° V';
            app.LLabel_4 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.LLabel_4.Text = 'L';
            app.EditField_42 = uieditfield(app.REGULACINDEVOLTAJEPanel, 'numeric');
            app.EditField_42.Editable = 'off';
            app.ALabel_10 = uilabel(app.REGULACINDEVOLTAJEPanel);
            app.ALabel_10.Text = '° A';
            app.PLENACARGAPanel = uipanel(app.PanelTransformadores);
            app.PLENACARGAPanel.Title = 'PLENA CARGA';
            app.PotenciaaparenteEditFieldLabel = uilabel(app.PLENACARGAPanel);
            app.PotenciaaparenteEditFieldLabel.Text = 'Potencia aparente';
            app.PotenciaaparenteEditField = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.PotenciaaparenteEditField.Editable = 'off';
            app.FactordepotenciaEditFieldLabel = uilabel(app.PLENACARGAPanel);
            app.FactordepotenciaEditFieldLabel.Text = 'Factor de potencia';
            app.FactordepotenciaEditField = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.VoltajeEditField_3Label = uilabel(app.PLENACARGAPanel);
            app.VoltajeEditField_3Label.Text = 'Voltaje';
            app.VoltajeEditField_3 = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.VoltajeEditField_3.Editable = 'off';
            app.CorrienteEditField_3Label = uilabel(app.PLENACARGAPanel);
            app.CorrienteEditField_3Label.Text = 'Corriente';
            app.CorrienteEditField_3 = uieditfield(app.PLENACARGAPanel, 'numeric');
            app.CorrienteEditField_3.Editable = 'off';
            app.KVALabel_3 = uilabel(app.PLENACARGAPanel);
            app.KVALabel_3.Text = 'KVA';
            app.Label_43 = uilabel(app.PLENACARGAPanel);
            app.Label_43.Text = '';
            app.VLabel_20 = uilabel(app.PLENACARGAPanel);
            app.VLabel_20.Text = 'V';
            app.ALabel_9 = uilabel(app.PLENACARGAPanel);
            app.ALabel_9.Text = 'A';
            app.Label_44 = uilabel(app.PLENACARGAPanel);
            app.Label_44.Text = '(-)';
            app.RegulacindeVoltajeGaugeLabel = uilabel(app.PanelTransformadores);
            app.RegulacindeVoltajeGaugeLabel.Text = 'Regulación de Voltaje (%)';
            app.RegulacindeVoltajeGauge = uigauge(app.PanelTransformadores, 'semicircular');
            app.RegulacindeVoltajeGauge.Limits = [0 10];
            app.RegulacindeVoltajeGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.EficienciaGaugeLabel = uilabel(app.PanelTransformadores);
            app.EficienciaGaugeLabel.Text = 'Eficiencia (%)';
            app.EficienciaGauge = uigauge(app.PanelTransformadores, 'semicircular');
            app.EficienciaGauge.Limits = [50 100];
            app.EficienciaGauge.MajorTicks = [50 55 60 65 70 75 80 85 90 95 100];
            app.PUBasePanel = uipanel(app.PanelTransformadores);
            app.PUBasePanel.Title = 'PU Base';
            app.SbEditFieldLabel = uilabel(app.PUBasePanel);
            app.SbEditFieldLabel.Text = 'Sb';
            app.SbEditField = uieditfield(app.PUBasePanel, 'numeric');
            app.SbEditField.Editable = 'off';
            app.Vb1EditFieldLabel = uilabel(app.PUBasePanel);
            app.Vb1EditFieldLabel.Text = 'Vb1';
            app.Vb1EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Vb1EditField.Editable = 'off';
            app.Vb2EditFieldLabel = uilabel(app.PUBasePanel);
            app.Vb2EditFieldLabel.Text = 'Vb2';
            app.Vb2EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Vb2EditField.Editable = 'off';
            app.Ib1EditFieldLabel = uilabel(app.PUBasePanel);
            app.Ib1EditFieldLabel.Text = 'Ib1';
            app.Ib1EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Ib1EditField.Editable = 'off';
            app.Ib2EditFieldLabel = uilabel(app.PUBasePanel);
            app.Ib2EditFieldLabel.Text = 'Ib2';
            app.Ib2EditField = uieditfield(app.PUBasePanel, 'numeric');
            app.Ib2EditField.Editable = 'off';
            app.KVALabel = uilabel(app.PUBasePanel);
            app.KVALabel.Text = 'KVA';
            app.ALabel = uilabel(app.PUBasePanel);
            app.ALabel.Text = 'A';
            app.VLabel_11 = uilabel(app.PUBasePanel);
            app.VLabel_11.Text = 'V';
            app.VLabel_12 = uilabel(app.PUBasePanel);
            app.VLabel_12.Text = 'V';
            app.ALabel_2 = uilabel(app.PUBasePanel);
            app.ALabel_2.Text = 'A';
            app.AppCompensacinTab = uitab(app.TabGroup);
            app.AppCompensacinTab.Title = 'App. Compensación';
            app.imgCompensacion = uibutton(app.AppCompensacinTab, 'push');
            app.imgCompensacion.Icon = 'TEC 3.jpeg';
            app.imgCompensacion.Text = '';
            app.ButtonCompensacion = uibutton(app.AppCompensacinTab, 'push');
            app.ButtonCompensacion.ButtonPushedFcn = createCallbackFcn(app, @ButtonCompensacionPushed, true);
            app.ButtonCompensacion.Icon = 'Logo Compensacion.jpeg';
            app.ButtonCompensacion.Text = '';
            app.ClculoyCompensacindeunSistemaElctricodeACLabel = uilabel(app.AppCompensacinTab);
            app.ClculoyCompensacindeunSistemaElctricodeACLabel.Text = 'Cálculo y Compensación de un Sistema Eléctrico de AC';
            app.Label = uilabel(app.AppCompensacinTab);
            app.Label.Text = '';
            app.PanelCompensacion = uipanel(app.AppCompensacinTab);
            app.DatosdeentradaalsistemaPanel = uipanel(app.PanelCompensacion);
            app.DatosdeentradaalsistemaPanel.Title = 'Datos de entrada al sistema';
            app.TipoDropDownLabel = uilabel(app.DatosdeentradaalsistemaPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.DatosdeentradaalsistemaPanel);
            app.TipoDropDown.Items = {'Circuitos RL serie', 'Circuitos RC serie'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'Circuitos RL serie';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.DatosdeentradaalsistemaPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.DatosdeentradaalsistemaPanel, 'numeric');
            app.VoltajeVEditFieldLabel = uilabel(app.DatosdeentradaalsistemaPanel);
            app.VoltajeVEditFieldLabel.Text = 'Voltaje (V)';
            app.VoltajeVEditField = uieditfield(app.DatosdeentradaalsistemaPanel, 'numeric');
            app.nguloEditFieldLabel = uilabel(app.DatosdeentradaalsistemaPanel);
            app.nguloEditFieldLabel.Text = 'Ángulo';
            app.nguloEditField = uieditfield(app.DatosdeentradaalsistemaPanel, 'numeric');
            app.ResistenciaEditFieldLabel = uilabel(app.DatosdeentradaalsistemaPanel);
            app.ResistenciaEditFieldLabel.Text = 'Resistencia (Ω)';
            app.ResistenciaEditField = uieditfield(app.DatosdeentradaalsistemaPanel, 'numeric');
            app.InductorButtonGroup = uibuttongroup(app.DatosdeentradaalsistemaPanel);
            app.InductorButtonGroup.Title = 'Inductor';
            app.InductanciamHButton = uiradiobutton(app.InductorButtonGroup);
            app.InductanciamHButton.Text = 'Inductancia (mH)';
            app.InductanciamHButton.Value = true;
            app.ReactanciaButton = uiradiobutton(app.InductorButtonGroup);
            app.ReactanciaButton.Text = 'Reactancia (Ω)';
            app.EditField_43 = uieditfield(app.InductorButtonGroup, 'numeric');
            app.SliderLabel = uilabel(app.DatosdeentradaalsistemaPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.DatosdeentradaalsistemaPanel);
            app.Slider.Limits = [1 200];
            app.Slider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '200'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Enable = 'off';
            app.Slider.Value = 100;
            app.CONTROLESPanel = uipanel(app.PanelCompensacion);
            app.CONTROLESPanel.Title = 'CONTROLES';
            app.LimpiarCamposSwitch_5Label = uilabel(app.CONTROLESPanel);
            app.LimpiarCamposSwitch_5Label.Text = 'Limpiar Campos';
            app.LimpiarCamposSwitch_5 = uiswitch(app.CONTROLESPanel, 'slider');
            app.LimpiarCamposSwitch_5.ValueChangedFcn = createCallbackFcn(app, @LimpiarCamposSwitch_5ValueChanged, true);
            app.CompensarButton = uibutton(app.CONTROLESPanel, 'push');
            app.CompensarButton.ButtonPushedFcn = createCallbackFcn(app, @CompensarButtonPushed, true);
            app.CompensarButton.Text = 'Compensar';
            app.CalcularButton_4 = uibutton(app.CONTROLESPanel, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.UIAxes_7 = uiaxes(app.PanelCompensacion);
            app.PotenciaAparenteVAGaugeLabel = uilabel(app.PanelCompensacion);
            app.PotenciaAparenteVAGaugeLabel.Text = 'Potencia Aparente (VA)';
            app.PotenciaAparenteVAGauge = uigauge(app.PanelCompensacion, 'circular');
            app.PotenciaAparenteVAGauge.Limits = [0 3000];
            app.PotenciaActivaWGaugeLabel = uilabel(app.PanelCompensacion);
            app.PotenciaActivaWGaugeLabel.Text = 'Potencia Activa (W)';
            app.PotenciaActivaWGauge = uigauge(app.PanelCompensacion, 'circular');
            app.PotenciaActivaWGauge.Limits = [0 3000];
            app.PotenciaReactivaVARGaugeLabel = uilabel(app.PanelCompensacion);
            app.PotenciaReactivaVARGaugeLabel.Text = 'Potencia Reactiva (VAR)';
            app.PotenciaReactivaVARGauge = uigauge(app.PanelCompensacion, 'circular');
            app.PotenciaReactivaVARGauge.Limits = [-3000 3000];
            app.PotenciaReactivaVARGauge.MajorTicks = [-3000 -2250 -1500 -750 0 750 1500 2250 3000];
            app.ResultadosPanel = uipanel(app.PanelCompensacion);
            app.ResultadosPanel.Title = 'Resultados';
            app.ListBox = uilistbox(app.ResultadosPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.magI1EditField = uieditfield(app.ResultadosPanel, 'numeric');
            app.magI1EditField.Editable = 'off';
            app.magI1EditField.Enable = 'off';
            app.angI1EditField = uieditfield(app.ResultadosPanel, 'numeric');
            app.angI1EditField.Editable = 'off';
            app.angI1EditField.Enable = 'off';
            app.AppArmnicosTab = uitab(app.TabGroup);
            app.AppArmnicosTab.Title = 'App. Armónicos';
            app.imgArmonicos = uibutton(app.AppArmnicosTab, 'push');
            app.imgArmonicos.Icon = 'TEC 2.jpeg';
            app.imgArmonicos.Text = '';
            app.ButtonArmonicos = uibutton(app.AppArmnicosTab, 'push');
            app.ButtonArmonicos.ButtonPushedFcn = createCallbackFcn(app, @ButtonArmonicosPushed, true);
            app.ButtonArmonicos.Icon = 'Logo Armonicos.jpeg';
            app.ButtonArmonicos.Text = '';
            app.MedidordeArmnicos = uilabel(app.AppArmnicosTab);
            app.MedidordeArmnicos.Text = 'Medidor de Armónicos';
            app.MedidordeArmnicos_4 = uilabel(app.AppArmnicosTab);
            app.MedidordeArmnicos_4.Text = '';
            app.PanelArmonicos = uipanel(app.AppArmnicosTab);
            app.Label_6 = uilabel(app.PanelArmonicos);
            app.Label_6.Text = '';
            app.UIAxes3 = uiaxes(app.PanelArmonicos);
            title(app.UIAxes3, 'Composición de Armónicos')
            xlabel(app.UIAxes3, 'Tiempo (seg)')
            ylabel(app.UIAxes3, 'Amplitud (V)')
            app.Panel = uipanel(app.PanelArmonicos);
            app.FundamentalPanel_2 = uipanel(app.Panel);
            app.FundamentalPanel_2.Title = 'Fundamental';
            app.AmplitudEditField_2Label = uilabel(app.FundamentalPanel_2);
            app.AmplitudEditField_2Label.Text = 'Amplitud';
            app.AmplitudEditField_2 = uieditfield(app.FundamentalPanel_2, 'numeric');
            app.FrecuenciaEditField_2Label = uilabel(app.FundamentalPanel_2);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_2 = uieditfield(app.FundamentalPanel_2, 'numeric');
            app.VLabel_6 = uilabel(app.FundamentalPanel_2);
            app.VLabel_6.Text = 'V';
            app.HzLabel_2 = uilabel(app.FundamentalPanel_2);
            app.HzLabel_2.Text = 'Hz';
            app.AmplituddelosArmnicosPanel_2 = uipanel(app.Panel);
            app.AmplituddelosArmnicosPanel_2.Title = 'Amplitud de los Armónicos';
            app.erarmnicoEditField_2Label = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.erarmnicoEditField_2Label.Text = '3er armónico';
            app.erarmnicoEditField_2 = uieditfield(app.AmplituddelosArmnicosPanel_2, 'numeric');
            app.toarmnicoEditField_2Label = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.toarmnicoEditField_2Label.Text = '5to armónico';
            app.toarmnicoEditField_2 = uieditfield(app.AmplituddelosArmnicosPanel_2, 'numeric');
            app.moarmnicoEditField_2Label = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.moarmnicoEditField_2Label.Text = '7mo armónico';
            app.moarmnicoEditField_2 = uieditfield(app.AmplituddelosArmnicosPanel_2, 'numeric');
            app.voarmnicoEditField_2Label = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.voarmnicoEditField_2Label.Text = '11vo armónico';
            app.voarmnicoEditField_2 = uieditfield(app.AmplituddelosArmnicosPanel_2, 'numeric');
            app.VLabel_7 = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.VLabel_7.Text = 'V';
            app.VLabel_8 = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.VLabel_8.Text = 'V';
            app.VLabel_9 = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.VLabel_9.Text = 'V';
            app.VLabel_10 = uilabel(app.AmplituddelosArmnicosPanel_2);
            app.VLabel_10.Text = 'V';
            app.THDPanel_2 = uipanel(app.Panel);
            app.THDPanel_2.Title = 'THD';
            app.EditField_3 = uieditfield(app.THDPanel_2, 'numeric');
            app.EditField_3.Editable = 'off';
            app.Label_11 = uilabel(app.THDPanel_2);
            app.Label_11.Text = '%';
            app.CONTROLESButtonGroup_2 = uibuttongroup(app.Panel);
            app.CONTROLESButtonGroup_2.Title = 'CONTROLES';
            app.CalcularButton_2 = uibutton(app.CONTROLESButtonGroup_2, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.LimpiarCamposSwitch_2Label = uilabel(app.CONTROLESButtonGroup_2);
            app.LimpiarCamposSwitch_2Label.Text = 'Limpiar Campos';
            app.LimpiarCamposSwitch_2 = uiswitch(app.CONTROLESButtonGroup_2, 'slider');
            app.LimpiarCamposSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LimpiarCamposSwitch_2ValueChanged, true);
            app.InyeccindeArmnicosPanel_2 = uipanel(app.Panel);
            app.InyeccindeArmnicosPanel_2.Title = 'Inyección de Armónicos';
            app.TercerarmnicoCheckBox_2 = uicheckbox(app.InyeccindeArmnicosPanel_2);
            app.TercerarmnicoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.TercerarmnicoCheckBox_2.Text = 'Tercer armónico.';
            app.TercerarmnicoCheckBox_2.Value = true;
            app.QuintoarmnicoCheckBox_2 = uicheckbox(app.InyeccindeArmnicosPanel_2);
            app.QuintoarmnicoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.QuintoarmnicoCheckBox_2.Text = 'Quinto armónico.';
            app.QuintoarmnicoCheckBox_2.Value = true;
            app.SptimoarmnicoCheckBox_2 = uicheckbox(app.InyeccindeArmnicosPanel_2);
            app.SptimoarmnicoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.SptimoarmnicoCheckBox_2.Text = 'Séptimo armónico.';
            app.SptimoarmnicoCheckBox_2.Value = true;
            app.OnceavoarmnicoCheckBox_2 = uicheckbox(app.InyeccindeArmnicosPanel_2);
            app.OnceavoarmnicoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.OnceavoarmnicoCheckBox_2.Text = 'Onceavo armónico.';
            app.OnceavoarmnicoCheckBox_2.Value = true;
            app.FundamentalCheckBox_2 = uicheckbox(app.InyeccindeArmnicosPanel_2);
            app.FundamentalCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.FundamentalCheckBox_2.Text = 'Fundamental';
            app.FundamentalCheckBox_2.Value = true;
            app.NdeCiclosSpinner_2Label = uilabel(app.InyeccindeArmnicosPanel_2);
            app.NdeCiclosSpinner_2Label.Text = 'N° de Ciclos';
            app.NdeCiclosSpinner_2 = uispinner(app.InyeccindeArmnicosPanel_2);
            app.NdeCiclosSpinner_2.ValueChangingFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.NdeCiclosSpinner_2.Limits = [1 Inf];
            app.NdeCiclosSpinner_2.RoundFractionalValues = 'on';
            app.NdeCiclosSpinner_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.NdeCiclosSpinner_2.Value = 1;
            app.UIAxes4 = uiaxes(app.PanelArmonicos);
            title(app.UIAxes4, 'Distorsión Armonica Total')
            xlabel(app.UIAxes4, 'Orden del Armónico')
            ylabel(app.UIAxes4, 'Magnitud en % de la Fundamental')
            app.FundamentalLampLabel = uilabel(app.PanelArmonicos);
            app.FundamentalLampLabel.Text = 'Fundamental';
            app.FundamentalLamp = uilamp(app.PanelArmonicos);
            app.moLampLabel = uilabel(app.PanelArmonicos);
            app.moLampLabel.Text = '7mo';
            app.moLamp = uilamp(app.PanelArmonicos);
            app.voLampLabel = uilabel(app.PanelArmonicos);
            app.voLampLabel.Text = '11vo';
            app.voLamp = uilamp(app.PanelArmonicos);
            app.toLampLabel = uilabel(app.PanelArmonicos);
            app.toLampLabel.Text = '5to';
            app.toLamp = uilamp(app.PanelArmonicos);
            app.roLampLabel = uilabel(app.PanelArmonicos);
            app.roLampLabel.Text = '3ro';
            app.roLamp = uilamp(app.PanelArmonicos);
            app.ResultanteLampLabel = uilabel(app.PanelArmonicos);
            app.ResultanteLampLabel.Text = 'Resultante';
            app.ResultanteLamp = uilamp(app.PanelArmonicos);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SistemasElectricosDePotencia

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SistemasElctricosdePotenciaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SistemasElctricosdePotenciaUIFigure)
        end
    end
end